classdef AnalyZe < matlab.apps.AppBase
    %ANALYZE EIS (Impedance) data analysis toolbox
    %   Copyright <2023> <Douglas van Niekerk>
    %   
    %   Permission is hereby granted, free of charge, to any person
    %   obtaining a copy of this software and associated documentation
    %   files (the “Software”), to deal in the Software without
    %   restriction, including without limitation the rights to use, copy,
    %   modify, merge, publish, distribute, sublicense, and/or sell copies
    %   of the Software, and to permit persons to whom the Software is
    %   furnished to do so, subject to the following conditions:
    %   
    %   The above copyright notice and this permission notice shall be
    %   included in all copies or substantial portions of the Software.
    %   
    %   THE SOFTWARE IS PROVIDED “AS IS”, WITHOUT WARRANTY OF ANY KIND,
    %   EXPRESS OR IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF
    %   MERCHANTABILITY, FITNESS FOR A PARTICULAR PURPOSE AND
    %   NONINFRINGEMENT. IN NO EVENT SHALL THE AUTHORS OR COPYRIGHT HOLDERS
    %   BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER LIABILITY, WHETHER IN AN
    %   ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM, OUT OF OR IN
    %   CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
    %   SOFTWARE.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        TimeSeriesMagnitudeCrossSectionButton  matlab.ui.control.Button
        FitEquivalentCircuitButton      matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        DouglasvanNiekerkVersion2Feb2023Label  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ImpedanceDataAnalysisToolLabel  matlab.ui.control.Label
        AnalyZeLabel                    matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        InportDataTab                   matlab.ui.container.Tab
        HOMEButton                      matlab.ui.control.Button
        SaveForLaterButton              matlab.ui.control.Button
        LoadFromPreviousSaveButton      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        LoadDataPanel                   matlab.ui.container.Panel
        HoldPlotSwitchLoad              matlab.ui.control.ToggleSwitch
        HoldPlotSwitchLabel             matlab.ui.control.Label
        WellNumberEditField             matlab.ui.control.EditField
        WellNumberEditFieldLabel        matlab.ui.control.Label
        ExperimentNumberEditField       matlab.ui.control.NumericEditField
        ExperimentNumberEditFieldLabel  matlab.ui.control.Label
        LOADButton                      matlab.ui.control.Button
        FindFileButton                  matlab.ui.control.Button
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        TimePointAUEditField            matlab.ui.control.NumericEditField
        ConditionEditField              matlab.ui.control.EditField
        ConditionEditFieldLabel         matlab.ui.control.Label
        TimePointAUEditFieldLabel       matlab.ui.control.Label
        LoadEISDat                      matlab.ui.control.UIAxes
        AnalysisCCTFITTab               matlab.ui.container.Tab
        HOMEButton_2                    matlab.ui.control.Button
        ClearResultsButton              matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        HoldPlotsSwitch                 matlab.ui.control.ToggleSwitch
        HoldPlotsSwitchLabel            matlab.ui.control.Label
        NyqResults                      matlab.ui.control.UIAxes
        BodeResults                     matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        ResultsTable                    matlab.ui.control.Table
        FitDiagnosticTab                matlab.ui.container.Tab
        HoldPlotsSwitch_FitDiagnostics  matlab.ui.control.ToggleSwitch
        HoldPlotsSwitch_2Label          matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        ResidualsTab                    matlab.ui.container.Tab
        Residuals                       matlab.ui.control.UIAxes
        DensityTab                      matlab.ui.container.Tab
        PlotStyleSwitch                 matlab.ui.control.Switch
        PlotStyleSwitchLabel            matlab.ui.control.Label
        GranularityEditField            matlab.ui.control.NumericEditField
        GranularityEditFieldLabel       matlab.ui.control.Label
        ImagAxisWidthEditField          matlab.ui.control.NumericEditField
        ImagAxisWidthEditFieldLabel     matlab.ui.control.Label
        RealAxisWidthEditField          matlab.ui.control.NumericEditField
        RealAxisWidthEditFieldLabel     matlab.ui.control.Label
        KDensity                        matlab.ui.control.UIAxes
        UpdateDiagnosticPlotsButton     matlab.ui.control.Button
        ImagQQ                          matlab.ui.control.UIAxes
        RealQQ                          matlab.ui.control.UIAxes
        SeriesPlotTab                   matlab.ui.container.Tab
        OptionsPanel                    matlab.ui.container.Panel
        SwitchAreaNormType              matlab.ui.control.Switch
        AreaNormalizeSwitch             matlab.ui.control.Switch
        AreaNormalizeSwitchLabel        matlab.ui.control.Label
        Areacm2EditField                matlab.ui.control.NumericEditField
        Areacm2EditFieldLabel           matlab.ui.control.Label
        ZScoreNormalizeSwitch_FitSeries  matlab.ui.control.Switch
        ZScoreNormalizeLabel            matlab.ui.control.Label
        HoldPlotsSwitch_FitSeries       matlab.ui.control.ToggleSwitch
        HoldPlotsSwitch_2Label_2        matlab.ui.control.Label
        PlotFromTableSelectionButton    matlab.ui.control.Button
        FitSeriesPlot                   matlab.ui.control.UIAxes
        RefreshDataOptionsButton        matlab.ui.control.Button
        FittingParams                   matlab.ui.container.Panel
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        CircuitListBox                  matlab.ui.control.ListBox
        CircuitListBoxLabel             matlab.ui.control.Label
        FitBlankOnlySwitch              matlab.ui.control.Switch
        FitBlankOnlySwitchLabel         matlab.ui.control.Label
        FitSequentiallySwitch           matlab.ui.control.Switch
        FitSequentiallySwitchLabel      matlab.ui.control.Label
        BlankFitMultiStartsEditField    matlab.ui.control.NumericEditField
        BlankFitMultiStartsEditFieldLabel  matlab.ui.control.Label
        MultiStartsEditField            matlab.ui.control.NumericEditField
        MultiStartsEditFieldLabel       matlab.ui.control.Label
        TrimData                        matlab.ui.container.Panel
        TimeListBox                     matlab.ui.control.ListBox
        TimeListBoxLabel                matlab.ui.control.Label
        ChooseButton                    matlab.ui.control.Button
        ChosenDataTable                 matlab.ui.control.Table
        WellNumberListBox               matlab.ui.control.ListBox
        WellNumberListBoxLabel          matlab.ui.control.Label
        ExperimentNumberListBox         matlab.ui.control.ListBox
        ExperimentNumberListBoxLabel    matlab.ui.control.Label
        ConditionListBox                matlab.ui.control.ListBox
        ConditionListBoxLabel           matlab.ui.control.Label
        AnalysisTimeSeriesMagnitudeCrossSectionTab  matlab.ui.container.Tab
        HOMEButton_3                    matlab.ui.control.Button
        RefreshDataOptionsButton_2      matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        PlotsTab_2                      matlab.ui.container.Tab
        LoadDataIntoResultsTableButton  matlab.ui.control.Button
        CSDataPlot                      matlab.ui.control.UIAxes
        CSResultsPlot                   matlab.ui.control.UIAxes
        ResultsTab_2                    matlab.ui.container.Tab
        SaveResultsButton_2             matlab.ui.control.Button
        ClearResultsButton_2            matlab.ui.control.Button
        ResultsTable_2                  matlab.ui.control.Table
        CrossSectionParametersPanel     matlab.ui.container.Panel
        NormalizationSchemeListBox      matlab.ui.control.ListBox
        NormalizationSchemeListBoxLabel  matlab.ui.control.Label
        MultipleTimeSeriesPanel         matlab.ui.container.Panel
        WaterFallSwitch                 matlab.ui.control.Switch
        WaterFallSwitchLabel            matlab.ui.control.Label
        OverlayTimeSeriesSwitch         matlab.ui.control.ToggleSwitch
        OverlayTimeSeriesSwitchLabel    matlab.ui.control.Label
        HzEditField                     matlab.ui.control.NumericEditField
        HzEditFieldLabel                matlab.ui.control.Label
        CrossSectionFrequencySlider     matlab.ui.control.Slider
        CrossSectionFrequencySliderLabel  matlab.ui.control.Label
        ZScoreNormalizeSwitch           matlab.ui.control.Switch
        ZScoreNormalizeSwitchLabel      matlab.ui.control.Label
        OffsetRemovalSwitch             matlab.ui.control.Switch
        OffsetRemovalSwitchLabel        matlab.ui.control.Label
        TrimData_2                      matlab.ui.container.Panel
        ChooseButton_2                  matlab.ui.control.Button
        ChosenDataTable_2               matlab.ui.control.Table
        WellNumberListBox_2             matlab.ui.control.ListBox
        WellNumberListBox_2Label        matlab.ui.control.Label
        ExperimentNumberListBox_2       matlab.ui.control.ListBox
        ExperimentNumberListBox_2Label  matlab.ui.control.Label
        ConditionListBox_2              matlab.ui.control.ListBox
        ConditionListBox_2Label         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentFileName % Description
        DatToFit % Description
         % Description
        DatToCrossSection % Description


    end
    
    properties (Access = public)
        CurrentEISMeasurement % Description
        CurrentEISTime = NaN;
        CurrentEISName = 'Null Condition';
        CurrentExpNumber = NaN;
        CurrentWell = 'Null Well';
        Data = struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'Data', {}); % Description
        Fits = struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'FitsResults', {},'RawData', {});% Description
        ResultTableRowSelected = NaN; % Description
        CrossSectionResultsCumulative = struct('Name', {'Start'},  'ExperimentNumber', {-1}, 'Well', {'A0'} , 'CSResults', {},'Indexes',{});% Description
        CrossSectionResultsCurrentCondition = struct('Name', {'Start'},  'ExperimentNumber', {-1}, 'Well', {'A0'} , 'CSResults', {});
 % Description
        CrossSectionIndex = NaN; % Description
        ChosenFreq = NaN % Description
        CS_xline % Description
        CrossSectionResultsLoaded = struct('CSFreq',{},'Name',{'Start'},'ExperimentNumber',{-1},'Well',{'A0'},'Time',{-1},'Mod',{-1},'Arg',{-1});
        ResultTableCellsSelected = NaN; % Description
        FitDiagnosticQQColourCounter = 1; % Description
         % Description
        KDensityFits_ColourMapCounter = 1;
    end
    
    methods (Access = private)
        
        
        function out = MultistartFit(app, y_z,freq,multi_starts,cct_type,sequential_Fit,blank_fit, varargin)
            %UNTITLED12 Summary of this function goes here
            %   Detailed explanation goes here
            % Variable Arguments:
            %                   y_z_blank
            %                   multi_starts_blank
                        
                    %% Sequential Fitting
                    
                        Blank_Fits = [];
                        if (sequential_Fit)
                                
                            y_z_blank = varargin{1};
            
                            multi_starts_blank = varargin{2};
                            Blank_Fits = app.MultistartFit(y_z_blank,...
                                                        freq,...
                                                        multi_starts_blank,...
                                                        cct_type,...
                                                        false,... %sequetial fit
                                                        true); %blank fit
                           
            
                            Blank_beta = Blank_Fits{1};
                        end
                        
            
                        %% Fit data setup
                            
                            R_inf = real(y_z(1));
                
                             C = @(c1,x) ( 1i.*x(:,1).*c1 ).^(-1);
                             L = @(l1,x) 1i.*x(:,1).*l1;
                             Q = @(c1,c2,x) ( c1.*( (1i.*x(:,1)).^(c2) ) ).^(-1);
            
                  
                            Bilayer = @(b,x) ( (1./b(1,:)) + (1./C(b(2,:),x)) ).^(-1);
                            Counter =  @(b,x) ( (1./b(3,:)) + (1./Q(b(4,:),b(5,:),x)) ).^(-1);
                            RandleCounter = @(b,x) ( (1./( b(3,:) + Q(b(4,:),0.5,x)  )) + (1./( C(b(5),x)  )) ).^(-1);
                            RandleCounter_BLANK = @(b,x) ( (1./( b(1,:) + Q(b(2,:),0.5,x)  )) + (1./( C(b(3),x)  )) ).^(-1);
                                
                                %%%%%%%%%%%%
                                     R_RNDL_UB = 1e6; %1e-6
                                %%%%%%%%%%%%
                    
                            CCT_Fn_2E = @(b,x)  R_inf + Bilayer(b,x) + RandleCounter(b,x);
                                beta0_2E = [1;1;1;1;1];
                                lb_2E = [1e-12;1e-12;1e-12;1e-12;1e-12];
                                ub_2E = [1e3;1;R_RNDL_UB;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                              
                                %b(3) = R_Counter
                                %b(4) = W_Counter
                                %b(5) = C_counter
                                CCT_Fn_2E_BLANK = @(b,x)  R_inf + RandleCounter_BLANK(b,x);
                                    beta0_2E_BLANK = [1;1;1];
                                    lb_2E_BLANK = [1e-12;1e-12;1e-12];
                                    ub_2E_BLANK = [R_RNDL_UB;1;1]; 
                                if (sequential_Fit)
                                    CCT_Fn_2E_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + RandleCounter_BLANK(Blank_beta,x);
                                        beta0_2E_SEQUENTIAL = [1;1];
                                        lb_2E_SEQUENTIAL = [1e-12;1e-12];
                                        ub_2E_SEQUENTIAL = [1e3;1]; 
                                end
                                
            
                            CCT_Fn_3E = @(b,x)  R_inf + Bilayer(b,x) + Q(b(3),1,x);
                                beta0_3E = [1;1;1];
                                lb_3E = [1e-12;1e-12;1e-3];
                                ub_3E = [1e3;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                
                                %b(3) = C_PEDOT:PSS
                                %b(4) = alpha_counter
                                CCT_Fn_3E_BLANK = @(b,x)  R_inf +  Q(b(1),1,x);
                                    beta0_3E_BLANK = [1];
                                    lb_3E_BLANK = [1e-3];
                                    ub_3E_BLANK = [1]; 
                                if (sequential_Fit)
                                    CCT_Fn_3E_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + Q(Blank_beta(1),1,x);
                                        beta0_3E_SEQUENTIAL = [1;1];
                                        lb_3E_SEQUENTIAL = [1e-12;1e-12];
                                        ub_3E_SEQUENTIAL = [1e3;1]; 
                                end
            
            
                          CCT_Fn_3E_SS = @(b,x)  R_inf + Bilayer(b,x) + Q(b(3),1,x) + Q(b(4),b(5),x);
                                beta0_3E_SS = [1;1;1;1;1];
                                lb_3E_SS = [1e-12;1e-12;1e-3;0;0];
                                ub_3E_SS = [1e3;1;1;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                
                                %b(3) = C_PEDOT:PSS
            
                                %b(4) = Q_RE
                                %b(5) = alpha_RE
                                CCT_Fn_3E_SS_BLANK = @(b,x)  R_inf + Q(b(1),1,x);
                                    beta0_3E_SS_BLANK = [1;1;1];
                                    lb_3E_SS_BLANK = [1e-3;0;0];
                                    ub_3E_SS_BLANK = [1;1;1];
                               if (sequential_Fit)
                                   CCT_Fn_3E_SS_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + Q(Blank_beta(1),1,x) +  + Q(b(3),b(4),x);
                                        beta0_3E_SS_SEQUENTIAL = [1;1;1;1];
                                        lb_3E_SS_SEQUENTIAL = [1e-12;1e-12;0;0];
                                        ub_3E_SS_SEQUENTIAL = [1e3;1;1;1];  
                               end


                                CCT_Fn_2E_SS = @(b,x)  R_inf + Bilayer(b,x) + Q(b(3),b(4),x);
                                beta0_2E_SS = [1;1;1;1];
                                lb_2E_SS = [1e-12;1e-12;0;0];
                                ub_2E_SS = [1e3;1;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                
                                %b(3) = C_PEDOT:PSS
            
                                %b(4) = Q_RE
                                %b(5) = alpha_RE
                                CCT_Fn_2E_SS_BLANK = @(b,x)  R_inf + Q(b(1),b(2),x);
                                    beta0_2E_SS_BLANK = [1;1;1];
                                    lb_2E_SS_BLANK = [0;0;0];
                                    ub_2E_SS_BLANK = [1;1;1];
                               if (sequential_Fit)
                                   CCT_Fn_2E_SS_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + Q(Blank_beta(1),Blank_beta(2),x);
                                        beta0_2E_SS_SEQUENTIAL = [1;1;1;1];
                                        lb_2E_SS_SEQUENTIAL = [1e-12;1e-12;0;0];
                                        ub_2E_SS_SEQUENTIAL = [1e3;1;1;1];  
                               end


                        if (cct_type == "3E")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_3E_SEQUENTIAL;
                                beta0 = beta0_3E_SEQUENTIAL;
                                lb = lb_3E_SEQUENTIAL;
                                ub = ub_3E_SEQUENTIAL;
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_3E_BLANK;
                                beta0 = beta0_3E_BLANK;
                                lb = lb_3E_BLANK;
                                ub = ub_3E_BLANK;
                            else
                                CCT_Fn = CCT_Fn_3E;
                                beta0 = beta0_3E;
                                lb = lb_3E;
                                ub = ub_3E;
                            end
                            
                        elseif (cct_type == "2E")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_2E_SEQUENTIAL;
                                beta0 = beta0_2E_SEQUENTIAL;
                                lb = lb_2E_SEQUENTIAL;
                                ub = ub_2E_SEQUENTIAL;
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_2E_BLANK;
                                beta0 = beta0_2E_BLANK;
                                lb = lb_2E_BLANK;
                                ub = ub_2E_BLANK;
                            else
                                CCT_Fn = CCT_Fn_2E;
                                beta0 = beta0_2E;
                                lb = lb_2E;
                                ub = ub_2E;
                            end
            
                        elseif (cct_type == "3E_SS")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_3E_SS_SEQUENTIAL;
                                beta0 = beta0_3E_SS_SEQUENTIAL;
                                lb = lb_3E_SS_SEQUENTIAL;
                                ub = ub_3E_SS_SEQUENTIAL;
            
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_3E_SS_BLANK;
                                beta0 = beta0_3E_SS_BLANK;
                                lb = lb_3E_SS_BLANK;
                                ub = ub_3E_SS_BLANK;
            
                            else
                                CCT_Fn = CCT_Fn_3E_SS;
                                beta0 = beta0_3E_SS;
                                lb = lb_3E_SS;
                                ub = ub_3E_SS;
            
                            end
            
                            elseif (cct_type == "2E_SS")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_2E_SS_SEQUENTIAL;
                                beta0 = beta0_2E_SS_SEQUENTIAL;
                                lb = lb_2E_SS_SEQUENTIAL;
                                ub = ub_2E_SS_SEQUENTIAL;
            
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_2E_SS_BLANK;
                                beta0 = beta0_2E_SS_BLANK;
                                lb = lb_2E_SS_BLANK;
                                ub = ub_2E_SS_BLANK;
            
                            else
                                CCT_Fn = CCT_Fn_2E_SS;
                                beta0 = beta0_2E_SS;
                                lb = lb_2E_SS;
                                ub = ub_2E_SS;
            
                            end
                        end
            
                        %display(CCT_Fn)
            
                %% FIT Data
                
            %             if (blank_fit)
            %                 freq_Scale = 1./(2*pi.*freq);
            %             else
            %                 freq_Scale =  (2*pi.*freq);; %freq./max(freq);
            %             end
                        freq_Scale = 1;
                        weighting = 1./( (real(y_z)).^2 + (imag(y_z)).^2 );
                        Real_Scale = weighting; % 1./real(y_z);
                        Imag_Scale = weighting; % 1./imag(y_z);
                        CCT_Fn_Lik = @(b) (sum( Real_Scale.*freq_Scale.*(real(y_z) - real(CCT_Fn(b,2*pi.*freq))).^2 ) ) + (sum( Imag_Scale.*freq_Scale.*(imag(y_z) - imag(CCT_Fn(b,2*pi.*freq))).^2 ) );% - 100.*log10(Upper-b(1))+ 1e3*norm(b-MaxLik,2)^2 %+ 0.001*(b(1)-500)^2
            
                            A = [];
                            b = [];
                            Aeq = [];
                            beq = [];
            
            
                        rng default % For reproducibility
                        problem = createOptimProblem('fmincon','objective',...
                            CCT_Fn_Lik,'x0',beta0,'lb',lb,'ub',ub);
            
                        ms = MultiStart;
                        ms.UseParallel = true;
                        ms.Display = 'iter';
                        ms.PlotFcn = @gsplotbestf;
                       % ms.StartPointsToRun = 'bounds';
            
                        [bet,fval] = run(ms,problem,multi_starts)
            
            %% OUTPUT
            
                        GoF_MSE = goodnessOfFit(CCT_Fn(bet,2*pi*freq),y_z,'MSE');
                        GoF_NRMSE = goodnessOfFit(CCT_Fn(bet,2*pi*freq),y_z,'NRMSE');
                        GoF_NMSE = goodnessOfFit(CCT_Fn(bet,2*pi*freq),y_z,'NMSE');
            
                        GoF = [GoF_MSE, GoF_NRMSE, GoF_NMSE];
            
                        out{1} = bet;
                        out{2} = fval;
                        out{3} = GoF;
                        out{4} = CCT_Fn(bet,2*pi.*freq);
            
                        if (sequential_Fit)
            
                            out{5} = Blank_Fits;
            
                        end
            
            
            end
        
            function [] = CrossSection(app,CS_val,LoadNew)

            
            CS_Freq = CS_val;

            Dat_full = app.DatToCrossSection;
            
            CurrentSelection = true;
            CS_local = app.getCS(CurrentSelection,CS_Freq);

            %% Store Results
                    Dat_i = Dat_full(1);


                      switch (app.OverlayTimeSeriesSwitch.Value)
                          case 'On'
                             
                              if LoadNew
                                        app.CrossSectionResultsCumulative(end+1) = struct('Name', {Dat_i.Name},...
                                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                          'Well', {Dat_i.Well} ,...
                                          'CSResults', (CS_local),...
                                          'Indexes', {app.CrossSectionIndex}...
                                          );

                                        app.CrossSectionResultsCurrentCondition = struct('Name', {Dat_i.Name},...
                                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                          'Well', {Dat_i.Well} ,...
                                          'CSResults', (CS_local)...
                                          );
                              else
                                                                   
                                  app.CrossSectionResultsCumulative(end) = struct('Name', {Dat_i.Name},...
                                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                          'Well', {Dat_i.Well} ,...
                                          'CSResults', (CS_local),...
                                          'Indexes', {app.CrossSectionIndex}...
                                          );
                                  app.CrossSectionResultsCurrentCondition(end+1) = struct('Name', {Dat_i.Name},...
                                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                          'Well', {Dat_i.Well} ,...
                                          'CSResults', (CS_local)...
                                          );
              
                              end
                               
                                
                              switch (app.WaterFallSwitch.Value)
                                  case 'On'
                                        
                                        app.CS_xline = xline(app.CSDataPlot,CS_Freq,'LineWidth',3);

                                      app.CrossSectionResultsCurrentCondition(end+1) = struct('Name', {Dat_i.Name},...
                                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                          'Well', {Dat_i.Well} ,...
                                          'CSResults', (CS_local)...
                                          );

                                      if (length(app.CrossSectionResultsCurrentCondition) == 1 )
                                            app.CrossSectionResultsCurrentCondition(end+1) = struct('Name', {Dat_i.Name},...
                                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                          'Well', {Dat_i.Well} ,...
                                          'CSResults', (CS_local)...
                                          );
                                      end
                                        
                                        CS_last = app.CrossSectionResultsCurrentCondition(end);
                                        Time_last = CS_last.CSResults.Time;
                                        Freqs = [1:length(app.CrossSectionResultsCurrentCondition)];


                                        Z = [];
                                        
                                        for (j = 1:length(app.CrossSectionResultsCurrentCondition))
                                            temp = app.CrossSectionResultsCurrentCondition(j);
                                            Z = [Z;abs(temp.CSResults.y_z)];

                                            Freqs(j) =  temp.CSResults.CSFreq(1);
                                        end

                                        [X,Y] = meshgrid(Time_last,Freqs);
                                        
                                      
                                      cla(app.CSResultsPlot,'reset')
                                 
                                        waterfall(app.CSResultsPlot,X,Y,Z)
                                        xlabel(app.CSResultsPlot,'Time')
                                        ylabel(app.CSResultsPlot,'Frequency (Hz)')
                                        zlabel(app.CSResultsPlot,'|Z|')

                                  case 'Off'

                                                                           
                                    if (length(app.CrossSectionResultsCumulative) > 1)
                                       
                                        hold(app.CSResultsPlot, 'off');
                                        
                                        delete(app.CS_xline)
                                        app.CS_xline = xline(app.CSDataPlot,CS_Freq,'LineWidth',3);

                                        for (i=1:length(app.CrossSectionResultsCumulative))
                                            CS_i = app.CrossSectionResultsCumulative(i);
                                            
                                            Ind = CS_i.Indexes;
                                          
                                            CS_i.CSResults = app.getCS(false,CS_Freq,(Ind));
                                            app.CrossSectionResultsCumulative(i) = CS_i;

                                            plot(app.CSResultsPlot, CS_i.CSResults.Time, abs(CS_i.CSResults.y_z), '-*', 'LineWidth',2)
                                            hold(app.CSResultsPlot, 'on');
                                        end
                                    else
                                        app.CS_xline = xline(app.CSDataPlot,CS_Freq,'LineWidth',3);

                                        for (i=1:length(app.CrossSectionResultsCumulative))
                                            CS_i = app.CrossSectionResultsCumulative(i);
                                            plot(app.CSResultsPlot, CS_i.CSResults.Time, abs(CS_i.CSResults.y_z), '-*', 'LineWidth',2)
                                            hold(app.CSResultsPlot, 'on');
                                        end
                                    end
                              end
                                

                          case 'Off'
                               delete(app.CS_xline)
                              app.CS_xline = xline(app.CSDataPlot,CS_Freq,'LineWidth',3);

                              app.CrossSectionResultsCumulative = [];
                              app.CrossSectionResultsCurrentCondition = struct('Name', {'Start'},  'ExperimentNumber', {-1}, 'Well', {'A0'} , 'CSResults', {});

                              app.CrossSectionResultsCumulative = struct('Name', {Dat_i.Name},...
                                  'ExperimentNumber', {Dat_i.ExperimentNumber},...
                                  'Well', {Dat_i.Well} ,...
                                  'CSResults', (CS_local),...
                                  'Indexes', {app.CrossSectionIndex}...
                                  );


                              hold(app.CSResultsPlot, 'off');
                              cla(app.CSResultsPlot,'reset')

                              plot(app.CSResultsPlot, CS_local.Time, abs(CS_local.y_z), '-*', 'LineWidth',2)

                              xlabel(app.CSResultsPlot,'Time')
                            ylabel(app.CSResultsPlot,'|Z|')

                      end


        end
        
        function results = getCS(app,CurrentSelection,CS_Freq,varargin)

           
            if CurrentSelection
                Dat_full = app.DatToCrossSection;
            else
                Indexes = varargin{1};
                Dat = app.Data;
                    Dat = Dat(Indexes{1});
                        Dat = Dat(Indexes{2});
                            Dat = Dat(Indexes{3});

                Dat_full = Dat;
            end

            NumDays = length(Dat_full);

            CS_local = [];
            for (i = 1:NumDays)
                
                Dat_i = Dat_full(i);
                Dat_i_EIS = Dat_i.Data;
                y_z_i = Dat_i_EIS.Z - 1j*Dat_i_EIS.Z1;
                freq_i = Dat_i_EIS.FrequencyHz;

                
                index = find(freq_i>=CS_Freq);
                
                index = index(end);

                y_z_CS_i = y_z_i(index);

                switch (app.OffsetRemovalSwitch.Value)
                    case 'On'
                        y_z_CS_i = y_z_CS_i - abs(y_z_i(1));
                    case 'Off'
                end

                CS_local.y_z(i) = y_z_CS_i;
                CS_local.CSFreq(i) = CS_Freq;
                CS_local.Time(i) = Dat_i.Time;

            end

            switch (app.ZScoreNormalizeSwitch.Value)
                case 'On'
                    Scheme = string(app.NormalizationSchemeListBox.Value);

                    if (Scheme == "range")
                        CS_local.y_z = normalize(abs(CS_local.y_z),Scheme);
                    else
                       CS_local.y_z = normalize((CS_local.y_z),Scheme);
                    end
                case 'Off'
            end

            results = CS_local;
            
        end


        function results = getSliderValue(app,val)

            min_f = min(app.ChosenFreq);
            max_f = max(app.ChosenFreq);

           
            results = (10^(val*log10(max_f)))+min_f-1;

            
        end
        
        function [] = setSliderVal(app,val)

            min_f = min(app.ChosenFreq);
            max_f = max(app.ChosenFreq);

            value = (log10((val+1-min_f)))/log10(max_f);
            
            if(value>1)
                value = 1;
            end

            app.CrossSectionFrequencySlider.Value =  value;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindFileButton
        function FindFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               %disp('User selected Cancel');
            else
               %disp(['User selected ', fullfile(path,file)]);
            end

            app.FilePathEditField.Value =  fullfile(path,file) ;
            app.CurrentFileName = fullfile(path,file);
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)


            %% Set up the Import Options and import the data
            opts = delimitedTextImportOptions("NumVariables", 7);
            
            % Specify range and delimiter
            opts.DataLines = [2, Inf];
            opts.Delimiter = "\t";

                %Auto-detect_delimeter
                   AutoOpts = detectImportOptions(app.CurrentFileName);
                   opts.Delimiter = string(AutoOpts.Delimiter{1});
            
            % Specify column names and types
            opts.VariableNames = ["Index", "FrequencyHz", "Z", "Z1", "Z2", "Phase", "Times"];
            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double"];
            
            % Specify file level properties
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            
            % Import the data
            app.CurrentEISMeasurement = readtable(app.CurrentFileName, opts);
            
            
            %% Clear temporary variables
            clear opts

            %% Save Data

            %struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'Data', {});

                app.Data(end+1) = struct('Name', {app.CurrentEISName}, 'Time', {app.CurrentEISTime}, 'ExperimentNumber', {app.CurrentExpNumber},...
                    'Well', {app.CurrentWell} ,  'Data', (app.CurrentEISMeasurement));
                %Data2Save = app.Data;
                %save("Test.mat","Data2Save")


            %% Plot
                EISDat = app.CurrentEISMeasurement;
                Freq = EISDat.FrequencyHz;
                Mod = EISDat.Z2;
                Arg = EISDat.Phase;

                switch app.HoldPlotSwitchLoad.Value
                        case 'On'
                            hold(app.LoadEISDat, 'on');

                        case 'Off'
                            hold(app.LoadEISDat, 'off');
                            cla(app.LoadEISDat)
                    end
                
                yyaxis(app.LoadEISDat, 'left')
                plot(app.LoadEISDat, Freq, Mod,'LineWidth',3)
                set(app.LoadEISDat,'YScale','log')
                set(app.LoadEISDat,'XScale','log')
                hold(app.LoadEISDat, 'on')
                     yyaxis(app.LoadEISDat, 'right')
                     plot(app.LoadEISDat, Freq, Arg,'LineWidth',3)
                     set(app.LoadEISDat,'YScale','linear')
                hold(app.LoadEISDat, 'on')

           %% Add Table Entry
                 newData = {app.CurrentEISName app.CurrentExpNumber app.CurrentWell app.CurrentEISTime};
                 app.UITable.Data = [app.UITable.Data; newData];
        end

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
            value = app.FilePathEditField.Value;
            app.CurrentFileName = value;
        end

        % Value changed function: TimePointAUEditField
        function TimePointAUEditFieldValueChanged(app, event)
            value = app.TimePointAUEditField.Value;
            app.CurrentEISTime = value;
        end

        % Value changed function: ConditionEditField
        function ConditionEditFieldValueChanged(app, event)
            value = app.ConditionEditField.Value;
            app.CurrentEISName = value;
        end

        % Value changed function: ExperimentNumberEditField
        function ExperimentNumberEditFieldValueChanged(app, event)
            value = app.ExperimentNumberEditField.Value;
            app.CurrentExpNumber = value;
        end

        % Value changed function: WellNumberEditField
        function WellNumberEditFieldValueChanged(app, event)
            value = app.WellNumberEditField.Value;
            app.CurrentWell = value;
        end

        % Button pushed function: SaveForLaterButton
        function SaveForLaterButtonPushed(app, event)
           UserFileName = inputdlg("Enter File Name:");

            selpath = uigetdir();
            SavedData = app.Data;
            save(selpath + "\AnalyZeData_" + string(UserFileName) + ".mat","SavedData",'-mat');

            f = msgbox("Data Saved as AnalyZeData_" + string(UserFileName) + ".mat :)");
        end

        % Button pushed function: LoadFromPreviousSaveButton
        function LoadFromPreviousSaveButtonPushed(app, event)
             [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);

               var = load(fullfile(path,file));
               app.Data = var.SavedData;
            end
            
            app.UITable.Data = [];
            for (i = 1:length(app.Data))
                var = app.Data;
                var = var(i);
                newData = {var.Name var.ExperimentNumber var.Well var.Time};
                app.UITable.Data = [app.UITable.Data; newData];
            end
        

            cla(app.LoadEISDat)
            hold(app.LoadEISDat, 'on');
                            
             for (i = 1:length(app.Data))
                EISDat = app.Data(i);
                EISDat = EISDat.Data;
                Freq = EISDat.FrequencyHz;
                Mod = EISDat.Z2;
                Arg = EISDat.Phase;
                
                yyaxis(app.LoadEISDat, 'left')
                plot(app.LoadEISDat, Freq, Mod,'LineWidth',3)
                set(app.LoadEISDat,'YScale','log')
                set(app.LoadEISDat,'XScale','log')
                
                     yyaxis(app.LoadEISDat, 'right')
                     plot(app.LoadEISDat, Freq, Arg,'LineWidth',3)
                     set(app.LoadEISDat,'YScale','linear')
                
             end
              hold(app.LoadEISDat, 'off');

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;


        end

        % Button pushed function: RefreshDataOptionsButton
        function RefreshDataOptionsButtonPushed(app, event)
                Dat = app.Data;

                Conditions = "Select All";
                Exp = "Select All";
                Well = "Select All";
                Time = "Select All";
                for (i=1:length(Dat))
                    Conditions(i+1) = Dat(i).Name;
                    Exp(i+1) = Dat(i).ExperimentNumber;
                    Well(i+1) = Dat(i).Well;
                    Time(i+1) = Dat(i).Time;
                end
                
                Conditions = unique(Conditions);
                Exp = unique(Exp);
                Well = unique(Well);
                Time = unique(Time);

                app.ConditionListBox.Items = Conditions;
                app.ExperimentNumberListBox.Items = Exp;
                app.WellNumberListBox.Items = Well;
                app.TimeListBox.Items = Time;
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            Condition = app.ConditionListBox.Value;
            Well = app.WellNumberListBox.Value;
            Exp = app.ExperimentNumberListBox.Value;
            Time = app.TimeListBox.Value;

            Dat = app.Data;

                ConditionsAll = "";
                ExpAll = "";
                WellAll = "";
                TimeAll = "";
                for (i=1:length(Dat))
                    ConditionsAll(i) = Dat(i).Name;
                    ExpAll(i) = Dat(i).ExperimentNumber;
                    WellAll(i) = Dat(i).Well;
                    TimeAll(i) = Dat(i).Time;
                end
                
               Indexes = [];
               switch Condition
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(ConditionsAll == Condition);
               end
                 Dat = Dat(Indexes);
                 ExpAll = ExpAll(Indexes);
                 WellAll = WellAll(Indexes);
                 TimeAll = TimeAll(Indexes);


             Indexes = [];
               switch Exp
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(ExpAll == Exp);
               end
                 Dat = Dat(Indexes);
                 WellAll = WellAll(Indexes);
                 TimeAll = TimeAll(Indexes);

             Indexes = [];
               switch Well
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(WellAll == Well);
               end
                 Dat = Dat(Indexes);
                 TimeAll = TimeAll(Indexes);

             Indexes = [];
               switch Time
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(TimeAll == Time);
               end
                Dat = Dat(Indexes);

             app.ChosenDataTable.Data = [];
            for (i = 1:length(Dat))
                var = Dat;
                var = var(i);
                newData = {var.Name var.ExperimentNumber var.Well var.Time};
                app.ChosenDataTable.Data = [app.ChosenDataTable.Data; newData];
            end

           app.DatToFit = Dat;

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
    
           %% Assemble Parameters

            Dat_full = app.DatToFit;

            NumDays = length(Dat_full);
            NumDevices =1;

            multi_starts = app.MultiStartsEditField.Value;
            multi_starts_blank = app.BlankFitMultiStartsEditField.Value;
           
            %fit_sequentially = true;

            switch app.FitSequentiallySwitch.Value
                case 'On'
                    fit_sequentially = true;
                case 'Off'
                    fit_sequentially = false;
            end

           switch app.FitBlankOnlySwitch.Value
                case 'On'
                    fit_blank_only = true;
                    fit_sequentially = false;
                    multi_starts = multi_starts_blank;
                case 'Off'
                    fit_blank_only = false;
            end

            fit_cct = [];
            switch app.CircuitListBox.Value
                case 'R--(R//C)--((R--W)//C)' 
                    fit_cct = "2E";
                case 'R--(R//C)--C'
                    fit_cct = "3E";
                case 'R--(R//C)--C--Q'
                    fit_cct = "3E_SS";
                case 'R--(R//C)--Q'
                    fit_cct = "2E_SS";
                otherwise
                    warning('Unexpected circuit type. No plot created.')
            end
        
            %% Fit CCT
       

            app.RunningLamp.Color = 'green';
            drawnow()

            Fits_local = [];
            for (i = 1:NumDays)

                Dat_i = Dat_full(i);
                Dat_i_EIS = Dat_i.Data;
                y_z_i = Dat_i_EIS.Z - 1j*Dat_i_EIS.Z1;
                freq_i = Dat_i_EIS.FrequencyHz;


                 Fits_local{i} = app.MultistartFit( y_z_i,... %y_z
                                            freq_i,... %freq
                                            multi_starts,... %multi_starts
                                            fit_cct,... %cct type
                                            fit_sequentially,... %fit blank first
                                            fit_blank_only, ... %fit blank now
                                            y_z_i,... %y_z blank
                                            multi_starts_blank... %multi starts
                                            ); 

                 %app.RunningLamp.Color = [0.93,0.69,0.13];

                  %% Store Results
                      app.Fits(end+1) = struct('Name', {Dat_i.Name},...
                          'Time', {Dat_i.Time},...
                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                          'Well', {Dat_i.Well} ,...
                          'FitsResults', (Fits_local(i)),...
                          'RawData', y_z_i...
                          );

                  %% Add Table Entry
                        Results = Fits_local{i};
                        GoF_results = Results{3};
                        Beta_result = Results{1};


                        if (fit_blank_only)
                            Rb_Result = NaN;
                            Cb_Result = NaN;

                           Beta_device_results = Beta_result;

                        else
                            Rb_Result = Beta_result(1);
                            Cb_Result = Beta_result(2);

                            if (fit_sequentially)
                                Sequential_Blank_Result = Results{5};
                                Beta_device_results = cell2mat(Sequential_Blank_Result(1));
                            else
                                Beta_device_results = (Beta_result(3:end));
                            end
                        end

                        Beta_device_results = mat2str(Beta_device_results,4);
                        
                     newData = {app.CircuitListBox.Value Dat_i.Name Dat_i.ExperimentNumber Dat_i.Well Dat_i.Time Rb_Result Cb_Result Beta_device_results GoF_results(1) GoF_results(2) GoF_results(3)};
                     DataToAddToTable = [app.ResultsTable.Data; newData];
                     app.ResultsTable.Data = DataToAddToTable;


                  %% plot Results
                    
                    Results = Fits_local{i};
                    %display(Results)
                    Results_yz = Results{4};
                    Mod_Results = abs(Results_yz);
                    Arg_Results = -1.*angle(Results_yz)*(180/pi);
                    
                    Freq = Dat_i_EIS.FrequencyHz;
                    Mod = Dat_i_EIS.Z2;
                    Arg = Dat_i_EIS.Phase;

                    switch app.HoldPlotsSwitch.Value
                        case 'On'
                            hold(app.BodeResults, 'on');

                            hold(app.NyqResults,'on' );
                        case 'Off'
                            hold(app.BodeResults, 'off');
                            cla(app.BodeResults)

                            hold(app.NyqResults,'off' );
                            cla(app.NyqResults)
                    end
                                    
                    yyaxis(app.BodeResults, 'left')
                    plot(app.BodeResults, Freq, Mod, '*r','LineWidth',1)
                    set(app.BodeResults,'YScale','log')
                    set(app.BodeResults,'XScale','log')
                    xlabel(app.BodeResults,'Frequency (Hz)');
                    ylabel(app.BodeResults,'Magnitude, |Z|, (\Omega )');
                    hold(app.BodeResults, 'on')
                        plot(app.BodeResults, Freq, Mod_Results, '-r','LineWidth',1)

                         yyaxis(app.BodeResults, 'right')
                           ylabel(app.BodeResults,'-Phase, \angle Z (deg)');
                         plot(app.BodeResults, Freq, Arg, '*g','LineWidth',1)
                         plot(app.BodeResults, Freq, Arg_Results, '-g','LineWidth',1)
                         set(app.BodeResults,'YScale','linear')
                    hold(app.BodeResults, 'off')
                    
       
                    plot(app.NyqResults , Dat_i_EIS.Z, Dat_i_EIS.Z1, '*r','LineWidth',1)
                    hold(app.NyqResults, 'on')
                        plot(app.NyqResults , real(Results_yz), -1.*imag(Results_yz), '-k','LineWidth',1)
                    hold(app.NyqResults, 'off')
                    xlabel(app.NyqResults,'Real(Z)');
                    ylabel( app.NyqResults, '-Imag(Z)');

                    drawnow()
                    
            end
            
            app.RunningLamp.Color = 'red';
            drawnow()


        end

        % Button pushed function: ClearResultsButton
        function ClearResultsButtonPushed(app, event)
             yyaxis(app.BodeResults, 'right')
             cla(app.BodeResults)
             yyaxis(app.BodeResults, 'left')
             cla(app.BodeResults)
             cla(app.NyqResults)
             cla(app.FitSeriesPlot)
             app.ResultsTable.Data = [];

             app.Fits = struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'FitsResults', {},'RawData', {});
             
             f = msgbox("Results Cleared!");
       
             
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            UserFileName = inputdlg("Enter File Name: ");
            
            selpath = uigetdir();
            SavedDataResults = app.Fits;
            save(selpath + "\AnalyZeResults_" + string(UserFileName) + ".mat","SavedDataResults",'-mat');

            T_c =  app.ResultsTable.Data;
            T = cell2table(T_c, 'VariableNames', {'circuit','Condition','exp','cell','Time','Rb','Cb','Device CCT Params','mse','rmse','nmse'});

            filename= selpath + "\AnalyZeResults_" + string(UserFileName) + ".csv";
            writetable(T,filename);

            f = msgbox("Data Saved as AnalyZeResults_" + string(UserFileName) + ".mat and AnalyZeResults_"  + string(UserFileName) + ".csv :D");
        end

        % Value changed function: FitBlankOnlySwitch
        function FitBlankOnlySwitchValueChanged(app, event)
            value = app.FitBlankOnlySwitch.Value;

            switch value
                case 'On'
                    app.FitSequentiallySwitch.Value = 'Off';
                case 'Off'
                   
            end
            
        end

        % Cell selection callback: ResultsTable
        function ResultsTableCellSelection(app, event)
            indices = event.Indices;
            
            if (length(indices) >= 1)
                %display(indices)
                app.ResultTableRowSelected = indices(1);
                app.ResultTableCellsSelected = indices;
            end

            
        end

        % Button pushed function: UpdateDiagnosticPlotsButton
        function UpdateDiagnosticPlotsButtonPushed(app, event)
            SelectedFits = app.Fits(app.ResultTableRowSelected);

            LocalResults = SelectedFits.FitsResults;
            Fits_y_z = LocalResults{4};
            Data_y_z = SelectedFits.RawData;

            Res = Data_y_z - Fits_y_z;

            switch app.HoldPlotsSwitch_FitDiagnostics.Value
                case 'On'
                    hold(app.RealQQ, 'on');
                    hold(app.ImagQQ, 'on');
                    hold(app.KDensity, 'on');
                    hold(app.Residuals, 'on');

                    app.FitDiagnosticQQColourCounter = app.FitDiagnosticQQColourCounter +1;
                        if app.FitDiagnosticQQColourCounter > 5
                            app.FitDiagnosticQQColourCounter = 1;
                        end
                    app.KDensityFits_ColourMapCounter = app.KDensityFits_ColourMapCounter +1;
                        if app.KDensityFits_ColourMapCounter > 6
                            app.KDensityFits_ColourMapCounter =1;
                        end
                    
                case 'Off'
                    hold(app.RealQQ, 'off');
                    hold(app.ImagQQ, 'off');
                    hold(app.KDensity, 'off');
                    hold(app.Residuals, 'off');

                    app.FitDiagnosticQQColourCounter = 1;
                    app.KDensityFits_ColourMapCounter = 1;
            end


            scatter(app.Residuals, real(Res) ,imag(Res),'*');

            cmap = hsv(6);

            qq1 = qqplot(app.RealQQ, real(Res) );
            title(app.RealQQ, 'QQ Plot Re(Residuals)')
            qq1(1).MarkerEdgeColor = cmap(end-app.FitDiagnosticQQColourCounter,:);
            qq2 = qqplot(app.ImagQQ, imag(Res) );
            title(app.ImagQQ, 'QQ Plot Im(Residuals)')
            qq2(1).MarkerEdgeColor = cmap(end-app.FitDiagnosticQQColourCounter,:);

            gridx1 = -app.RealAxisWidthEditField.Value:app.GranularityEditField.Value:app.RealAxisWidthEditField.Value;
            gridx2 = -app.ImagAxisWidthEditField.Value:app.GranularityEditField.Value:app.ImagAxisWidthEditField.Value;
            [x1,x2] = meshgrid(gridx1, gridx2);
            x1 = x1(:);
            x2 = x2(:);
            xi = [x1 x2];

            FaceAlfa = 1 - (1/app.KDensityFits_ColourMapCounter);
            switch app.PlotStyleSwitch.Value
                case 'Surface'
                    ksdensity(app.KDensity, [ real(Res) imag(Res) ], xi)
                case 'Contour'
                    ksdensity(app.KDensity, [ real(Res) imag(Res) ], xi, 'PlotFcn','contour')
            end
%             ColourMaps  = {colormap(app.KDensity,parula),...
%                 colormap(app.KDensity,autumn),...
%                 colormap(app.KDensity,hsv),...
%                 colormap(app.KDensity,spring),...
%                 colormap(app.KDensity,summer),...
%                 colormap(app.KDensity,hot)...
%                 };
%             colormap(app.KDensity,ColourMaps{app.KDensityFits_ColourMapCounter})


        end

        % Button pushed function: ChooseButton_2
        function ChooseButton_2Pushed(app, event)

            IndexRecord = [];

            Condition = app.ConditionListBox_2.Value;
            Well = app.WellNumberListBox_2.Value;
            Exp = app.ExperimentNumberListBox_2.Value;

            Dat = app.Data;

                ConditionsAll = "";
                ExpAll = "";
                WellAll = "";
                for (i=1:length(Dat))
                    ConditionsAll(i) = Dat(i).Name;
                    ExpAll(i) = Dat(i).ExperimentNumber;
                    WellAll(i) = Dat(i).Well;
                end
                
               Indexes = [];
               switch Condition
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(ConditionsAll == Condition);
               end
                 Dat = Dat(Indexes);
                 ExpAll = ExpAll(Indexes);
                 WellAll = WellAll(Indexes);

                 IndexRecord{1} = Indexes;


             Indexes = [];
               switch Exp
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(ExpAll == Exp);
               end
                 Dat = Dat(Indexes);
                 WellAll = WellAll(Indexes);

                 IndexRecord{2} = Indexes;

             Indexes = [];
               switch Well
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(WellAll == Well);
               end
                 Dat = Dat(Indexes);

                 IndexRecord{3} = Indexes;

             app.ChosenDataTable_2.Data = [];
            for (i = 1:length(Dat))
                var = Dat;
                var = var(i);
                newData = {var.Name var.ExperimentNumber var.Well var.Time};
                app.ChosenDataTable_2.Data = [app.ChosenDataTable_2.Data; newData];
            end

           app.DatToCrossSection = Dat;
           app.CrossSectionIndex = IndexRecord;
            
           if (length(Dat)>0)
                Dat_1 = Dat(1);
                Dat_1_EIS = Dat_1.Data;
                Dat_1_Freq = Dat_1_EIS.FrequencyHz;
                app.ChosenFreq = Dat_1_Freq;

                %app.CrossSectionFrequencySlider.Limits = [min(Dat_1_Freq), max(Dat_1_Freq)];
                app.CrossSectionFrequencySlider.Limits = [0, 1];
                app.CrossSectionFrequencySlider.MajorTicksMode = 'manual';
                app.CrossSectionFrequencySlider.MajorTicks = linspace(0,1,5);
                app.CrossSectionFrequencySlider.MajorTickLabelsMode = 'manual';
                a = (log10(min(Dat_1_Freq)));
                b = (log10(max(Dat_1_Freq)));
                app.CrossSectionFrequencySlider.MajorTickLabels =  compose("%5.0e",logspace(a,b,5));

                %app.CrossSectionFrequencySlider.Value = (max(Dat_1_Freq)+min(Dat_1_Freq))/2;
                 app.CrossSectionFrequencySlider.Value = 0.5;
                val = app.HzEditField.Value;
                CrossSection(app,val,true);


                %%Plot

                 cla(app.CSDataPlot,'reset')

                for (i = 1:length(Dat))
                    Dat_i = Dat(i);
                    Dat_i_EIS = Dat_i.Data;
                    Freq = Dat_i_EIS.FrequencyHz;
                    Mod = Dat_i_EIS.Z2;
                    Arg = Dat_i_EIS.Phase;


                    yyaxis(app.CSDataPlot, 'left')
                    plot(app.CSDataPlot, Freq, Mod, '-r','LineWidth',1)
                    set(app.CSDataPlot,'YScale','log')
                    set(app.CSDataPlot,'XScale','log')
                    xlabel(app.CSDataPlot,'Frequency (Hz)');
                    ylabel(app.CSDataPlot,'Magnitude, |Z|, (\Omega )');
                    hold(app.CSDataPlot, 'on')
                        
                         yyaxis(app.CSDataPlot, 'right')
                           ylabel(app.CSDataPlot,'-Phase, \angle Z (deg)');
                         plot(app.CSDataPlot, Freq, Arg, '-g','LineWidth',1)
                         set(app.BodeResults,'YScale','linear')
                    %hold(app.CSDataPlot, 'off')
                end
           end

           
        end

        % Value changed function: CrossSectionFrequencySlider
        function CrossSectionFrequencySliderValueChanged(app, event)
            value = app.CrossSectionFrequencySlider.Value;
            
            value = app.getSliderValue(value);

            app.HzEditField.Value = value;

            CrossSection(app,value,false);
        end

        % Value changed function: HzEditField
        function HzEditFieldValueChanged(app, event)
            value = app.HzEditField.Value;
            app.setSliderVal(value);
            
            
            CrossSection(app,value,false);
                

        end

        % Button pushed function: RefreshDataOptionsButton_2
        function RefreshDataOptionsButton_2Pushed(app, event)
            Dat = app.Data;

                Conditions = "Select All";
                Exp = "Select All";
                Well = "Select All";
                Time = "Select All";
                for (i=1:length(Dat))
                    Conditions(i+1) = Dat(i).Name;
                    Exp(i+1) = Dat(i).ExperimentNumber;
                    Well(i+1) = Dat(i).Well;
                    Time(i+1) = Dat(i).Time;
                end
                
                Conditions = unique(Conditions);
                Exp = unique(Exp);
                Well = unique(Well);
                Time = unique(Time);

                app.ConditionListBox_2.Items = Conditions;
                app.ExperimentNumberListBox_2.Items = Exp;
                app.WellNumberListBox_2.Items = Well;
                
        end

        % Value changing function: CrossSectionFrequencySlider
        function CrossSectionFrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            
            value = app.getSliderValue(changingValue);

            app.HzEditField.Value = value;

            %CrossSection(app,changingValue,false);
        end

        % Button pushed function: LoadDataIntoResultsTableButton
        function LoadDataIntoResultsTableButtonPushed(app, event)
          
            switch (app.OverlayTimeSeriesSwitch.Value)
                case 'On'
                        switch (app.WaterFallSwitch.Value)
                            case 'On'
                               LoopLen = length(app.CrossSectionResultsCurrentCondition);
                               ResultsToStore = app.CrossSectionResultsCurrentCondition ;

                               display("Waterfall")
                            otherwise
                                LoopLen = length(app.CrossSectionResultsCumulative);
                                ResultsToStore = app.CrossSectionResultsCumulative ;
                                if (LoopLen == 1)
                                    LoopLen = length(app.CrossSectionResultsCurrentCondition);
                                    ResultsToStore = app.CrossSectionResultsCurrentCondition ;
                                end
                                
                        end  
                otherwise
                     LoopLen = length(app.CrossSectionResultsCumulative);
                     ResultsToStore = app.CrossSectionResultsCumulative ;
            end

            display(LoopLen)

            for (i = 1:LoopLen)
                CSRes_i = ResultsToStore(i);
    
                Name = CSRes_i.Name;
                Exp = CSRes_i.ExperimentNumber;
                Well = CSRes_i.Well;
                CSFreq = CSRes_i.CSResults.CSFreq(1);
    
                y_z_i = CSRes_i.CSResults.y_z;
                Time_i = CSRes_i.CSResults.Time;
    
                for (j=1:length(Time_i))
                    y_z = y_z_i(j);
                    time = Time_i(j);
    
                    app.CrossSectionResultsLoaded(end+i) = struct(...
                         'CSFreq', CSFreq,...
                         'Name', Name,...
                         'ExperimentNumber', Exp,...
                         'Well',Well,...
                         'Time',time,...
                         'Mod',abs(y_z),...
                         'Arg',(angle(y_z)*(180/pi))...
                    );

                    newData = {CSFreq,...
                                Name,...
                                Exp,...
                                Well,...
                                time,...
                                abs(y_z),...
                                (angle(y_z)*(180/pi))...
                    };

                    DataToAddToTable = [app.ResultsTable_2.Data; newData];
                    app.ResultsTable_2.Data = DataToAddToTable;

               end
                                        
            end

        end

        % Button pushed function: ClearResultsButton_2
        function ClearResultsButton_2Pushed(app, event)
            yyaxis(app.CSDataPlot, 'right')
             cla(app.CSDataPlot)
             yyaxis(app.CSDataPlot, 'left')
             cla(app.CSDataPlot)
             cla(app.CSResultsPlot)
             app.ResultsTable_2.Data = [];

            app.CrossSectionResultsCumulative = struct('Name', {'Start'},  'ExperimentNumber', {-1}, 'Well', {'A0'} , 'CSResults', {},'Indexes',{});% Description
            app.CrossSectionResultsCurrentCondition = struct('Name', {'Start'},  'ExperimentNumber', {-1}, 'Well', {'A0'} , 'CSResults', {});
            app.CrossSectionResultsLoaded = struct('CSFreq',{},'Name',{'Start'},'ExperimentNumber',{-1},'Well',{'A0'},'Time',{-1},'Mod',{-1},'Arg',{-1});
             
             f = msgbox("Results Cleared!");
        end

        % Button pushed function: SaveResultsButton_2
        function SaveResultsButton_2Pushed(app, event)
            UserFileName = inputdlg("Enter File Name: ");
            
            selpath = uigetdir();
            SavedDataResults = app.CrossSectionResultsLoaded;
            save(selpath + "\AnalyZeCrossSectionResults_" + string(UserFileName) + ".mat","SavedDataResults",'-mat');

            T_c =  app.ResultsTable_2.Data;
            T = cell2table(T_c, 'VariableNames', {'CrossSectionFreq','Condition','exp','well','Time','MagZ','AngleZ'});

            filename= selpath + "\AnalyZeCrossSectionResults_" + string(UserFileName) + ".csv";
            writetable(T,filename);

            f = msgbox("Data Saved as AnalyZeCrossSectionResults_" + string(UserFileName) + ".mat and AnalyZeCrossSectionResults_"  + string(UserFileName) + ".csv :D");
        end

        % Button pushed function: PlotFromTableSelectionButton
        function PlotFromTableSelectionButtonPushed(app, event)
            if (length(app.ResultTableCellsSelected)>2)
                
                switch app.HoldPlotsSwitch_FitSeries.Value

                    case 'On'
                        hold(app.FitSeriesPlot, 'on');
                    case 'Off'
                        hold(app.FitSeriesPlot, 'off');

                end

                ind = app.ResultTableCellsSelected;
                
                T_c =  app.ResultsTable.Data;
                
                T = cell2table(T_c, 'VariableNames',...
                   {'circuit','Condition','exp','cell','Time',...
                  'Rb','Cb','Device CCT Params','mse','rmse','nmse'});

                Names = app.ResultsTable.ColumnName;
                var1 = string(Names(ind(1,2)));
                var2 = string(Names(ind(2,2)))

                xlabel(app.FitSeriesPlot,var1);
                ylabel(app.FitSeriesPlot,var2);
                
                DatToPlot = table2array(T(ind(:,1),[var1, var2])) ;
                x = DatToPlot(:,1);
                y = DatToPlot(:,2);

                
                switch app.ZScoreNormalizeSwitch_FitSeries.Value
                    case 'On'
                      y = normalize(y);
                    case 'Off'
                end

                switch app.AreaNormalizeSwitch.Value
                    case 'On'
                        Area = app.Areacm2EditField.Value;
                        switch app.SwitchAreaNormType.Value
                            case '*A'
                                y = y.*Area;
                                ylabel(app.FitSeriesPlot,var2 + " \cdot cm^2");
                            case '*sqrt(A)'
                                y = y.*sqrt(Area);
                                ylabel(app.FitSeriesPlot,var2 + " \cdot cm");
                        end
                end
                

                plot(app.FitSeriesPlot, x , y , '*-','LineWidth',3)


                grid(app.FitSeriesPlot, 'on')

            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InportDataTab;
        end

        % Button pushed function: FitEquivalentCircuitButton
        function FitEquivalentCircuitButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AnalysisCCTFITTab;
        end

        % Button pushed function: TimeSeriesMagnitudeCrossSectionButton
        function TimeSeriesMagnitudeCrossSectionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AnalysisTimeSeriesMagnitudeCrossSectionTab;
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: HOMEButton_2
        function HOMEButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: HOMEButton_3
        function HOMEButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Bioelectronicist.png');
            app.AnalyZeLabel = uilabel(app.HomeTab);
            app.AnalyZeLabel.Text = 'AnalyZe';
            app.ImpedanceDataAnalysisToolLabel = uilabel(app.HomeTab);
            app.ImpedanceDataAnalysisToolLabel.Text = 'Impedance Data Analysis Tool';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BEST_Logo.png');
            app.DouglasvanNiekerkVersion2Feb2023Label = uilabel(app.HomeTab);
            app.DouglasvanNiekerkVersion2Feb2023Label.Text = 'Douglas van Niekerk Version 2 Feb 2023';
            app.ImportDataButton = uibutton(app.HomeTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.FitEquivalentCircuitButton = uibutton(app.HomeTab, 'push');
            app.FitEquivalentCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @FitEquivalentCircuitButtonPushed, true);
            app.FitEquivalentCircuitButton.Text = 'Fit Equivalent Circuit';
            app.TimeSeriesMagnitudeCrossSectionButton = uibutton(app.HomeTab, 'push');
            app.TimeSeriesMagnitudeCrossSectionButton.ButtonPushedFcn = createCallbackFcn(app, @TimeSeriesMagnitudeCrossSectionButtonPushed, true);
            app.TimeSeriesMagnitudeCrossSectionButton.Text = {'Time Series'; 'Magnitude Cross Section'};
            app.InportDataTab = uitab(app.TabGroup);
            app.InportDataTab.Title = 'Inport Data';
            app.LoadDataPanel = uipanel(app.InportDataTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadEISDat = uiaxes(app.LoadDataPanel);
            title(app.LoadEISDat, 'Bode Plot')
            xlabel(app.LoadEISDat, 'Frequency (Hz)')
            app.LoadEISDat.Tag = 'LoadEIS';
            app.TimePointAUEditFieldLabel = uilabel(app.LoadDataPanel);
            app.TimePointAUEditFieldLabel.Text = 'Time Point (AU)';
            app.ConditionEditFieldLabel = uilabel(app.LoadDataPanel);
            app.ConditionEditFieldLabel.Text = 'Condition';
            app.ConditionEditField = uieditfield(app.LoadDataPanel, 'text');
            app.ConditionEditField.ValueChangedFcn = createCallbackFcn(app, @ConditionEditFieldValueChanged, true);
            app.TimePointAUEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.TimePointAUEditField.ValueChangedFcn = createCallbackFcn(app, @TimePointAUEditFieldValueChanged, true);
            app.TimePointAUEditField.Value = -1;
            app.FilePathEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.LoadDataPanel, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.FindFileButton = uibutton(app.LoadDataPanel, 'push');
            app.FindFileButton.ButtonPushedFcn = createCallbackFcn(app, @FindFileButtonPushed, true);
            app.FindFileButton.Text = 'Find File';
            app.LOADButton = uibutton(app.LoadDataPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.ExperimentNumberEditFieldLabel = uilabel(app.LoadDataPanel);
            app.ExperimentNumberEditFieldLabel.Text = 'Experiment Number';
            app.ExperimentNumberEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.ExperimentNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentNumberEditFieldValueChanged, true);
            app.ExperimentNumberEditField.Value = -1;
            app.WellNumberEditFieldLabel = uilabel(app.LoadDataPanel);
            app.WellNumberEditFieldLabel.Text = 'Well Number';
            app.WellNumberEditField = uieditfield(app.LoadDataPanel, 'text');
            app.WellNumberEditField.ValueChangedFcn = createCallbackFcn(app, @WellNumberEditFieldValueChanged, true);
            app.HoldPlotSwitchLabel = uilabel(app.LoadDataPanel);
            app.HoldPlotSwitchLabel.Text = 'Hold Plot';
            app.HoldPlotSwitchLoad = uiswitch(app.LoadDataPanel, 'toggle');
            app.UITable = uitable(app.InportDataTab);
            app.UITable.ColumnName = {'Condition'; 'Experiment Number'; 'Well'; 'Time Point'};
            app.UITable.RowName = {};
            app.LoadFromPreviousSaveButton = uibutton(app.InportDataTab, 'push');
            app.LoadFromPreviousSaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromPreviousSaveButtonPushed, true);
            app.LoadFromPreviousSaveButton.Text = 'Load From Previous Save';
            app.SaveForLaterButton = uibutton(app.InportDataTab, 'push');
            app.SaveForLaterButton.ButtonPushedFcn = createCallbackFcn(app, @SaveForLaterButtonPushed, true);
            app.SaveForLaterButton.Text = 'Save For Later';
            app.HOMEButton = uibutton(app.InportDataTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.AnalysisCCTFITTab = uitab(app.TabGroup);
            app.AnalysisCCTFITTab.Title = 'Analysis CCT FIT';
            app.TrimData = uipanel(app.AnalysisCCTFITTab);
            app.TrimData.Title = 'Select Data';
            app.ConditionListBoxLabel = uilabel(app.TrimData);
            app.ConditionListBoxLabel.Text = 'Condition';
            app.ConditionListBox = uilistbox(app.TrimData);
            app.ExperimentNumberListBoxLabel = uilabel(app.TrimData);
            app.ExperimentNumberListBoxLabel.Text = 'Experiment Number';
            app.ExperimentNumberListBox = uilistbox(app.TrimData);
            app.WellNumberListBoxLabel = uilabel(app.TrimData);
            app.WellNumberListBoxLabel.Text = 'Well Number';
            app.WellNumberListBox = uilistbox(app.TrimData);
            app.ChosenDataTable = uitable(app.TrimData);
            app.ChosenDataTable.ColumnName = {'Condition'; 'Experiment Number'; 'Well'; 'Time Point'};
            app.ChosenDataTable.RowName = {};
            app.ChooseButton = uibutton(app.TrimData, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.TimeListBoxLabel = uilabel(app.TrimData);
            app.TimeListBoxLabel.Text = 'Time';
            app.TimeListBox = uilistbox(app.TrimData);
            app.FittingParams = uipanel(app.AnalysisCCTFITTab);
            app.FittingParams.Title = 'Choose Fitting Parameters';
            app.MultiStartsEditFieldLabel = uilabel(app.FittingParams);
            app.MultiStartsEditFieldLabel.Text = 'MultiStarts';
            app.MultiStartsEditField = uieditfield(app.FittingParams, 'numeric');
            app.MultiStartsEditField.Value = 3000;
            app.BlankFitMultiStartsEditFieldLabel = uilabel(app.FittingParams);
            app.BlankFitMultiStartsEditFieldLabel.Text = {'Blank Fit'; 'MultiStarts'};
            app.BlankFitMultiStartsEditField = uieditfield(app.FittingParams, 'numeric');
            app.BlankFitMultiStartsEditField.Value = 1000;
            app.FitSequentiallySwitchLabel = uilabel(app.FittingParams);
            app.FitSequentiallySwitchLabel.Text = 'Fit Sequentially';
            app.FitSequentiallySwitch = uiswitch(app.FittingParams, 'slider');
            app.FitBlankOnlySwitchLabel = uilabel(app.FittingParams);
            app.FitBlankOnlySwitchLabel.Text = 'Fit Blank Only';
            app.FitBlankOnlySwitch = uiswitch(app.FittingParams, 'slider');
            app.FitBlankOnlySwitch.ValueChangedFcn = createCallbackFcn(app, @FitBlankOnlySwitchValueChanged, true);
            app.CircuitListBoxLabel = uilabel(app.FittingParams);
            app.CircuitListBoxLabel.Text = 'Circuit';
            app.CircuitListBox = uilistbox(app.FittingParams);
            app.CircuitListBox.Items = {'R--(R//C)--((R--W)//C)', 'R--(R//C)--C', 'R--(R//C)--C--Q', 'R--(R//C)--Q'};
            app.CircuitListBox.Value = 'R--(R//C)--((R--W)//C)';
            app.GoButton = uibutton(app.FittingParams, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.RunningLampLabel = uilabel(app.FittingParams);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.FittingParams);
            app.RefreshDataOptionsButton = uibutton(app.AnalysisCCTFITTab, 'push');
            app.RefreshDataOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDataOptionsButtonPushed, true);
            app.RefreshDataOptionsButton.Text = 'Refresh Data Options';
            app.TabGroup2 = uitabgroup(app.AnalysisCCTFITTab);
            app.PlotsTab = uitab(app.TabGroup2);
            app.PlotsTab.Title = 'Plots';
            app.BodeResults = uiaxes(app.PlotsTab);
            xlabel(app.BodeResults, 'X')
            ylabel(app.BodeResults, 'Y')
            zlabel(app.BodeResults, 'Z')
            app.NyqResults = uiaxes(app.PlotsTab);
            xlabel(app.NyqResults, 'X')
            ylabel(app.NyqResults, 'Y')
            zlabel(app.NyqResults, 'Z')
            app.HoldPlotsSwitchLabel = uilabel(app.PlotsTab);
            app.HoldPlotsSwitchLabel.Text = 'Hold Plots';
            app.HoldPlotsSwitch = uiswitch(app.PlotsTab, 'toggle');
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.ResultsTable = uitable(app.ResultsTab);
            app.ResultsTable.ColumnName = {'Circuit'; 'Condition'; 'Exp'; 'Well'; 'Time'; 'Rb'; 'Cb'; 'Device CCT Params'; 'MSE'; 'NRMSE'; 'NMSE'};
            app.ResultsTable.RowName = {};
            app.ResultsTable.CellSelectionCallback = createCallbackFcn(app, @ResultsTableCellSelection, true);
            app.FitDiagnosticTab = uitab(app.TabGroup2);
            app.FitDiagnosticTab.Title = 'Fit Diagnostic';
            app.RealQQ = uiaxes(app.FitDiagnosticTab);
            title(app.RealQQ, 'Real QQ plot')
            xlabel(app.RealQQ, 'X')
            ylabel(app.RealQQ, 'Y')
            zlabel(app.RealQQ, 'Z')
            app.ImagQQ = uiaxes(app.FitDiagnosticTab);
            title(app.ImagQQ, 'Imaginary QQ Plot')
            xlabel(app.ImagQQ, 'X')
            ylabel(app.ImagQQ, 'Y')
            zlabel(app.ImagQQ, 'Z')
            app.UpdateDiagnosticPlotsButton = uibutton(app.FitDiagnosticTab, 'push');
            app.UpdateDiagnosticPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDiagnosticPlotsButtonPushed, true);
            app.UpdateDiagnosticPlotsButton.Text = 'Update Diagnostic Plots';
            app.TabGroup3 = uitabgroup(app.FitDiagnosticTab);
            app.ResidualsTab = uitab(app.TabGroup3);
            app.ResidualsTab.Title = 'Residuals';
            app.Residuals = uiaxes(app.ResidualsTab);
            title(app.Residuals, 'Residuals')
            xlabel(app.Residuals, 'Real')
            ylabel(app.Residuals, 'Imaginary')
            zlabel(app.Residuals, 'Z')
            app.DensityTab = uitab(app.TabGroup3);
            app.DensityTab.Title = 'Density';
            app.KDensity = uiaxes(app.DensityTab);
            title(app.KDensity, 'K-Density Estimate')
            xlabel(app.KDensity, 'Real')
            ylabel(app.KDensity, 'Imaginary')
            zlabel(app.KDensity, 'Z')
            app.RealAxisWidthEditFieldLabel = uilabel(app.DensityTab);
            app.RealAxisWidthEditFieldLabel.Text = 'Real Axis Width';
            app.RealAxisWidthEditField = uieditfield(app.DensityTab, 'numeric');
            app.RealAxisWidthEditField.Value = 100;
            app.ImagAxisWidthEditFieldLabel = uilabel(app.DensityTab);
            app.ImagAxisWidthEditFieldLabel.Text = 'Imag Axis Width';
            app.ImagAxisWidthEditField = uieditfield(app.DensityTab, 'numeric');
            app.ImagAxisWidthEditField.Value = 100;
            app.GranularityEditFieldLabel = uilabel(app.DensityTab);
            app.GranularityEditFieldLabel.Text = 'Granularity';
            app.GranularityEditField = uieditfield(app.DensityTab, 'numeric');
            app.GranularityEditField.Value = 1;
            app.PlotStyleSwitchLabel = uilabel(app.DensityTab);
            app.PlotStyleSwitchLabel.Text = 'Plot Style';
            app.PlotStyleSwitch = uiswitch(app.DensityTab, 'slider');
            app.PlotStyleSwitch.Items = {'Surface', 'Contour'};
            app.PlotStyleSwitch.Value = 'Surface';
            app.HoldPlotsSwitch_2Label = uilabel(app.FitDiagnosticTab);
            app.HoldPlotsSwitch_2Label.Text = 'Hold Plots';
            app.HoldPlotsSwitch_FitDiagnostics = uiswitch(app.FitDiagnosticTab, 'toggle');
            app.SeriesPlotTab = uitab(app.TabGroup2);
            app.SeriesPlotTab.Title = 'Series Plot';
            app.FitSeriesPlot = uiaxes(app.SeriesPlotTab);
            xlabel(app.FitSeriesPlot, 'X')
            ylabel(app.FitSeriesPlot, 'Y')
            zlabel(app.FitSeriesPlot, 'Z')
            app.PlotFromTableSelectionButton = uibutton(app.SeriesPlotTab, 'push');
            app.PlotFromTableSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFromTableSelectionButtonPushed, true);
            app.PlotFromTableSelectionButton.Text = 'Plot From Table Selection';
            app.HoldPlotsSwitch_2Label_2 = uilabel(app.SeriesPlotTab);
            app.HoldPlotsSwitch_2Label_2.Text = 'Hold Plots';
            app.HoldPlotsSwitch_FitSeries = uiswitch(app.SeriesPlotTab, 'toggle');
            app.OptionsPanel = uipanel(app.SeriesPlotTab);
            app.OptionsPanel.Title = 'Options';
            app.ZScoreNormalizeLabel = uilabel(app.OptionsPanel);
            app.ZScoreNormalizeLabel.Text = {'Z-Score'; ' Normalize'};
            app.ZScoreNormalizeSwitch_FitSeries = uiswitch(app.OptionsPanel, 'slider');
            app.Areacm2EditFieldLabel = uilabel(app.OptionsPanel);
            app.Areacm2EditFieldLabel.Text = 'Area (cm^2)';
            app.Areacm2EditField = uieditfield(app.OptionsPanel, 'numeric');
            app.Areacm2EditField.Value = 1;
            app.AreaNormalizeSwitchLabel = uilabel(app.OptionsPanel);
            app.AreaNormalizeSwitchLabel.Text = 'Area Normalize';
            app.AreaNormalizeSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.SwitchAreaNormType = uiswitch(app.OptionsPanel, 'slider');
            app.SwitchAreaNormType.Items = {'*A', '*sqrt(A)'};
            app.SwitchAreaNormType.Value = '*A';
            app.SaveResultsButton = uibutton(app.AnalysisCCTFITTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.ClearResultsButton = uibutton(app.AnalysisCCTFITTab, 'push');
            app.ClearResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResultsButtonPushed, true);
            app.ClearResultsButton.Text = 'Clear Results';
            app.HOMEButton_2 = uibutton(app.AnalysisCCTFITTab, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_2Pushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.AnalysisTimeSeriesMagnitudeCrossSectionTab = uitab(app.TabGroup);
            app.AnalysisTimeSeriesMagnitudeCrossSectionTab.Title = 'Analysis Time Series Magnitude Cross Section';
            app.TrimData_2 = uipanel(app.AnalysisTimeSeriesMagnitudeCrossSectionTab);
            app.TrimData_2.Title = 'Select Time Series';
            app.ConditionListBox_2Label = uilabel(app.TrimData_2);
            app.ConditionListBox_2Label.Text = 'Condition';
            app.ConditionListBox_2 = uilistbox(app.TrimData_2);
            app.ExperimentNumberListBox_2Label = uilabel(app.TrimData_2);
            app.ExperimentNumberListBox_2Label.Text = 'Experiment Number';
            app.ExperimentNumberListBox_2 = uilistbox(app.TrimData_2);
            app.WellNumberListBox_2Label = uilabel(app.TrimData_2);
            app.WellNumberListBox_2Label.Text = 'Well Number';
            app.WellNumberListBox_2 = uilistbox(app.TrimData_2);
            app.ChosenDataTable_2 = uitable(app.TrimData_2);
            app.ChosenDataTable_2.ColumnName = {'Condition'; 'Experiment Number'; 'Well'; 'Time Point'};
            app.ChosenDataTable_2.RowName = {};
            app.ChooseButton_2 = uibutton(app.TrimData_2, 'push');
            app.ChooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton_2Pushed, true);
            app.ChooseButton_2.Text = 'Choose';
            app.CrossSectionParametersPanel = uipanel(app.AnalysisTimeSeriesMagnitudeCrossSectionTab);
            app.CrossSectionParametersPanel.Title = 'Cross Section Parameters';
            app.OffsetRemovalSwitchLabel = uilabel(app.CrossSectionParametersPanel);
            app.OffsetRemovalSwitchLabel.Text = 'Offset Removal';
            app.OffsetRemovalSwitch = uiswitch(app.CrossSectionParametersPanel, 'slider');
            app.ZScoreNormalizeSwitchLabel = uilabel(app.CrossSectionParametersPanel);
            app.ZScoreNormalizeSwitchLabel.Text = 'Z-Score Normalize';
            app.ZScoreNormalizeSwitch = uiswitch(app.CrossSectionParametersPanel, 'slider');
            app.CrossSectionFrequencySliderLabel = uilabel(app.CrossSectionParametersPanel);
            app.CrossSectionFrequencySliderLabel.Text = 'Cross Section Frequency';
            app.CrossSectionFrequencySlider = uislider(app.CrossSectionParametersPanel);
            app.CrossSectionFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CrossSectionFrequencySliderValueChanged, true);
            app.CrossSectionFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @CrossSectionFrequencySliderValueChanging, true);
            app.HzEditFieldLabel = uilabel(app.CrossSectionParametersPanel);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.CrossSectionParametersPanel, 'numeric');
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @HzEditFieldValueChanged, true);
            app.MultipleTimeSeriesPanel = uipanel(app.CrossSectionParametersPanel);
            app.MultipleTimeSeriesPanel.Title = 'Multiple Time Series';
            app.OverlayTimeSeriesSwitchLabel = uilabel(app.MultipleTimeSeriesPanel);
            app.OverlayTimeSeriesSwitchLabel.Text = 'Overlay Time Series';
            app.OverlayTimeSeriesSwitch = uiswitch(app.MultipleTimeSeriesPanel, 'toggle');
            app.WaterFallSwitchLabel = uilabel(app.MultipleTimeSeriesPanel);
            app.WaterFallSwitchLabel.Text = 'WaterFall';
            app.WaterFallSwitch = uiswitch(app.MultipleTimeSeriesPanel, 'slider');
            app.NormalizationSchemeListBoxLabel = uilabel(app.CrossSectionParametersPanel);
            app.NormalizationSchemeListBoxLabel.Text = 'Normalization Scheme';
            app.NormalizationSchemeListBox = uilistbox(app.CrossSectionParametersPanel);
            app.NormalizationSchemeListBox.Items = {'zscore', 'norm', 'scale', 'range', 'center', 'medianiqr'};
            app.NormalizationSchemeListBox.Value = 'zscore';
            app.TabGroup4 = uitabgroup(app.AnalysisTimeSeriesMagnitudeCrossSectionTab);
            app.PlotsTab_2 = uitab(app.TabGroup4);
            app.PlotsTab_2.Title = 'Plots';
            app.CSResultsPlot = uiaxes(app.PlotsTab_2);
            title(app.CSResultsPlot, 'Title')
            xlabel(app.CSResultsPlot, 'X')
            ylabel(app.CSResultsPlot, 'Y')
            zlabel(app.CSResultsPlot, 'Z')
            app.CSDataPlot = uiaxes(app.PlotsTab_2);
            xlabel(app.CSDataPlot, 'X')
            ylabel(app.CSDataPlot, 'Y')
            zlabel(app.CSDataPlot, 'Z')
            app.LoadDataIntoResultsTableButton = uibutton(app.PlotsTab_2, 'push');
            app.LoadDataIntoResultsTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataIntoResultsTableButtonPushed, true);
            app.LoadDataIntoResultsTableButton.Text = 'Load Data Into Results Table';
            app.ResultsTab_2 = uitab(app.TabGroup4);
            app.ResultsTab_2.Title = 'Results';
            app.ResultsTable_2 = uitable(app.ResultsTab_2);
            app.ResultsTable_2.ColumnName = {'CSFreq'; 'Condition'; 'Exp'; 'Well'; 'Time'; 'Mag'; 'Arg'};
            app.ResultsTable_2.RowName = {};
            app.ResultsTable_2.SelectionType = 'row';
            app.ResultsTable_2.Multiselect = 'off';
            app.ClearResultsButton_2 = uibutton(app.ResultsTab_2, 'push');
            app.ClearResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearResultsButton_2Pushed, true);
            app.ClearResultsButton_2.Text = 'Clear Results';
            app.SaveResultsButton_2 = uibutton(app.ResultsTab_2, 'push');
            app.SaveResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_2Pushed, true);
            app.SaveResultsButton_2.Text = 'Save Results';
            app.RefreshDataOptionsButton_2 = uibutton(app.AnalysisTimeSeriesMagnitudeCrossSectionTab, 'push');
            app.RefreshDataOptionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshDataOptionsButton_2Pushed, true);
            app.RefreshDataOptionsButton_2.Text = 'Refresh Data Options';
            app.HOMEButton_3 = uibutton(app.AnalysisTimeSeriesMagnitudeCrossSectionTab, 'push');
            app.HOMEButton_3.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_3Pushed, true);
            app.HOMEButton_3.Text = 'HOME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyZe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end