classdef AnalyZe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        DouglasvNVersion1Feb2023Label   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        BetaLabel                       matlab.ui.control.Label
        ImpedanceDataAnalysisToolLabel  matlab.ui.control.Label
        AnalyZeLabel                    matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        InportDataTab                   matlab.ui.container.Tab
        SaveForLaterButton              matlab.ui.control.Button
        LoadFromPreviousSaveButton      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        LoadDataPanel                   matlab.ui.container.Panel
        HoldPlotSwitchLoad              matlab.ui.control.ToggleSwitch
        HoldPlotSwitchLabel             matlab.ui.control.Label
        WellNumberEditField             matlab.ui.control.EditField
        WellNumberEditFieldLabel        matlab.ui.control.Label
        ExperimentNumberEditField       matlab.ui.control.NumericEditField
        ExperimentNumberEditFieldLabel  matlab.ui.control.Label
        LOADButton                      matlab.ui.control.Button
        FindFileButton                  matlab.ui.control.Button
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        TimePointAUEditField            matlab.ui.control.NumericEditField
        ConditionEditField              matlab.ui.control.EditField
        ConditionEditFieldLabel         matlab.ui.control.Label
        TimePointAUEditFieldLabel       matlab.ui.control.Label
        LoadEISDat                      matlab.ui.control.UIAxes
        AnalysisCCTFITTab               matlab.ui.container.Tab
        ClearResultsButton              matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        HoldPlotsSwitch                 matlab.ui.control.ToggleSwitch
        HoldPlotsSwitchLabel            matlab.ui.control.Label
        NyqResults                      matlab.ui.control.UIAxes
        BodeResults                     matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        ResultsTable                    matlab.ui.control.Table
        RefreshDataOptionsButton        matlab.ui.control.Button
        FittingParams                   matlab.ui.container.Panel
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        CircuitListBox                  matlab.ui.control.ListBox
        CircuitListBoxLabel             matlab.ui.control.Label
        FitBlankOnlySwitch              matlab.ui.control.Switch
        FitBlankOnlySwitchLabel         matlab.ui.control.Label
        FitSequentiallySwitch           matlab.ui.control.Switch
        FitSequentiallySwitchLabel      matlab.ui.control.Label
        BlankFitMultiStartsEditField    matlab.ui.control.NumericEditField
        BlankFitMultiStartsEditFieldLabel  matlab.ui.control.Label
        MultiStartsEditField            matlab.ui.control.NumericEditField
        MultiStartsEditFieldLabel       matlab.ui.control.Label
        TrimData                        matlab.ui.container.Panel
        TimeListBox                     matlab.ui.control.ListBox
        TimeListBoxLabel                matlab.ui.control.Label
        ChooseButton                    matlab.ui.control.Button
        ChosenDataTable                 matlab.ui.control.Table
        WellNumberListBox               matlab.ui.control.ListBox
        WellNumberListBoxLabel          matlab.ui.control.Label
        ExperimentNumberListBox         matlab.ui.control.ListBox
        ExperimentNumberListBoxLabel    matlab.ui.control.Label
        ConditionListBox                matlab.ui.control.ListBox
        ConditionListBoxLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentFileName % Description
        DatToFit % Description
    end
    
    properties (Access = public)
        CurrentEISMeasurement % Description
        CurrentEISTime = NaN;
        CurrentEISName = 'Null Condition';
        CurrentExpNumber = NaN;
        CurrentWell = 'Null Well';
        Data = struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'Data', {}); % Description
        Fits = struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'FitsResults', {});% Description
    end
    
    methods (Access = private)
        
        
        function out = MultistartFit(app, y_z,freq,multi_starts,cct_type,sequential_Fit,blank_fit, varargin)
            %UNTITLED12 Summary of this function goes here
            %   Detailed explanation goes here
            % Variable Arguments:
            %                   y_z_blank
            %                   multi_starts_blank
                        
                    %% Sequential Fitting
                    
                        Blank_Fits = [];
                        if (sequential_Fit)
                                
                            y_z_blank = varargin{1};
            
                            multi_starts_blank = varargin{2};
                            Blank_Fits = app.MultistartFit(y_z_blank,...
                                                        freq,...
                                                        multi_starts_blank,...
                                                        cct_type,...
                                                        false,... %sequetial fit
                                                        true); %blank fit
                           
            
                            Blank_beta = Blank_Fits{1};
                        end
                        
            
                        %% Fit data setup
                            
                            R_inf = real(y_z(1));
                
                             C = @(c1,x) ( 1i.*x(:,1).*c1 ).^(-1);
                             L = @(l1,x) 1i.*x(:,1).*l1;
                             Q = @(c1,c2,x) ( c1.*( (1i.*x(:,1)).^(c2) ) ).^(-1);
            
                  
                            Bilayer = @(b,x) ( (1./b(1,:)) + (1./C(b(2,:),x)) ).^(-1);
                            Counter =  @(b,x) ( (1./b(3,:)) + (1./Q(b(4,:),b(5,:),x)) ).^(-1);
                            RandleCounter = @(b,x) ( (1./( b(3,:) + Q(b(4,:),0.5,x)  )) + (1./( C(b(5),x)  )) ).^(-1);
                            RandleCounter_BLANK = @(b,x) ( (1./( b(1,:) + Q(b(2,:),0.5,x)  )) + (1./( C(b(3),x)  )) ).^(-1);
                    
                            CCT_Fn_2E = @(b,x)  R_inf + Bilayer(b,x) + RandleCounter(b,x);
                                beta0_2E = [1;1;1;1;1];
                                lb_2E = [1e-12;1e-12;1e-12;1e-12;1e-12];
                                ub_2E = [1e3;1;1e-6;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                              
                                %b(3) = R_Counter
                                %b(4) = W_Counter
                                %b(5) = C_counter
                                CCT_Fn_2E_BLANK = @(b,x)  R_inf + RandleCounter_BLANK(b,x);
                                    beta0_2E_BLANK = [1;1;1];
                                    lb_2E_BLANK = [1e-12;1e-12;1e-12];
                                    ub_2E_BLANK = [1e-6;1;1]; 
                                if (sequential_Fit)
                                    CCT_Fn_2E_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + RandleCounter_BLANK(Blank_beta,x);
                                        beta0_2E_SEQUENTIAL = [1;1];
                                        lb_2E_SEQUENTIAL = [1e-12;1e-12];
                                        ub_2E_SEQUENTIAL = [1e3;1]; 
                                end
                                
            
                            CCT_Fn_3E = @(b,x)  R_inf + Bilayer(b,x) + Q(b(3),1,x);
                                beta0_3E = [1;1;1];
                                lb_3E = [1e-12;1e-12;1e-3];
                                ub_3E = [1e3;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                
                                %b(3) = C_PEDOT:PSS
                                %b(4) = alpha_counter
                                CCT_Fn_3E_BLANK = @(b,x)  R_inf +  Q(b(1),1,x);
                                    beta0_3E_BLANK = [1];
                                    lb_3E_BLANK = [1e-3];
                                    ub_3E_BLANK = [1]; 
                                if (sequential_Fit)
                                    CCT_Fn_3E_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + Q(Blank_beta(1),1,x);
                                        beta0_3E_SEQUENTIAL = [1;1];
                                        lb_3E_SEQUENTIAL = [1e-12;1e-12];
                                        ub_3E_SEQUENTIAL = [1e3;1]; 
                                end
            
            
                          CCT_Fn_3E_SS = @(b,x)  R_inf + Bilayer(b,x) + Q(b(3),1,x) + Q(b(4),b(5),x);
                                beta0_3E_SS = [1;1;1;1;1];
                                lb_3E_SS = [1e-12;1e-12;1e-3;0;0];
                                ub_3E_SS = [1e3;1;1;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                
                                %b(3) = C_PEDOT:PSS
            
                                %b(4) = Q_RE
                                %b(5) = alpha_RE
                                CCT_Fn_3E_SS_BLANK = @(b,x)  R_inf + Q(b(1),1,x);
                                    beta0_3E_SS_BLANK = [1;1;1];
                                    lb_3E_SS_BLANK = [1e-3;0;0];
                                    ub_3E_SS_BLANK = [1;1;1];
                               if (sequential_Fit)
                                   CCT_Fn_3E_SS_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + Q(Blank_beta(1),1,x) +  + Q(b(3),b(4),x);
                                        beta0_3E_SS_SEQUENTIAL = [1;1;1;1];
                                        lb_3E_SS_SEQUENTIAL = [1e-12;1e-12;0;0];
                                        ub_3E_SS_SEQUENTIAL = [1e3;1;1;1];  
                               end


                                CCT_Fn_2E_SS = @(b,x)  R_inf + Bilayer(b,x) + Q(b(3),b(4),x);
                                beta0_2E_SS = [1;1;1;1];
                                lb_2E_SS = [1e-12;1e-12;0;0];
                                ub_2E_SS = [1e3;1;1;1]; 
                                %b(1) = R_bilayer
                                %b(2) = C_bilayer
                
                                %b(3) = C_PEDOT:PSS
            
                                %b(4) = Q_RE
                                %b(5) = alpha_RE
                                CCT_Fn_2E_SS_BLANK = @(b,x)  R_inf + Q(b(1),b(2),x);
                                    beta0_2E_SS_BLANK = [1;1;1];
                                    lb_2E_SS_BLANK = [0;0;0];
                                    ub_2E_SS_BLANK = [1;1;1];
                               if (sequential_Fit)
                                   CCT_Fn_2E_SS_SEQUENTIAL = @(b,x)  R_inf + Bilayer(b,x) + Q(Blank_beta(1),Blank_beta(2),x);
                                        beta0_2E_SS_SEQUENTIAL = [1;1;1;1];
                                        lb_2E_SS_SEQUENTIAL = [1e-12;1e-12;0;0];
                                        ub_2E_SS_SEQUENTIAL = [1e3;1;1;1];  
                               end


                        if (cct_type == "3E")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_3E_SEQUENTIAL;
                                beta0 = beta0_3E_SEQUENTIAL;
                                lb = lb_3E_SEQUENTIAL;
                                ub = ub_3E_SEQUENTIAL;
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_3E_BLANK;
                                beta0 = beta0_3E_BLANK;
                                lb = lb_3E_BLANK;
                                ub = ub_3E_BLANK;
                            else
                                CCT_Fn = CCT_Fn_3E;
                                beta0 = beta0_3E;
                                lb = lb_3E;
                                ub = ub_3E;
                            end
                            
                        elseif (cct_type == "2E")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_2E_SEQUENTIAL;
                                beta0 = beta0_2E_SEQUENTIAL;
                                lb = lb_2E_SEQUENTIAL;
                                ub = ub_2E_SEQUENTIAL;
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_2E_BLANK;
                                beta0 = beta0_2E_BLANK;
                                lb = lb_2E_BLANK;
                                ub = ub_2E_BLANK;
                            else
                                CCT_Fn = CCT_Fn_2E;
                                beta0 = beta0_2E;
                                lb = lb_2E;
                                ub = ub_2E;
                            end
            
                        elseif (cct_type == "3E_SS")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_3E_SS_SEQUENTIAL;
                                beta0 = beta0_3E_SS_SEQUENTIAL;
                                lb = lb_3E_SS_SEQUENTIAL;
                                ub = ub_3E_SS_SEQUENTIAL;
            
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_3E_SS_BLANK;
                                beta0 = beta0_3E_SS_BLANK;
                                lb = lb_3E_SS_BLANK;
                                ub = ub_3E_SS_BLANK;
            
                            else
                                CCT_Fn = CCT_Fn_3E_SS;
                                beta0 = beta0_3E_SS;
                                lb = lb_3E_SS;
                                ub = ub_3E_SS;
            
                            end
            
                            elseif (cct_type == "2E_SS")
            
                            if (sequential_Fit)
                                CCT_Fn = CCT_Fn_2E_SS_SEQUENTIAL;
                                beta0 = beta0_2E_SS_SEQUENTIAL;
                                lb = lb_2E_SS_SEQUENTIAL;
                                ub = ub_2E_SS_SEQUENTIAL;
            
                            elseif (blank_fit)
                                CCT_Fn = CCT_Fn_2E_SS_BLANK;
                                beta0 = beta0_2E_SS_BLANK;
                                lb = lb_2E_SS_BLANK;
                                ub = ub_2E_SS_BLANK;
            
                            else
                                CCT_Fn = CCT_Fn_2E_SS;
                                beta0 = beta0_2E_SS;
                                lb = lb_2E_SS;
                                ub = ub_2E_SS;
            
                            end
                        end
            
                        %display(CCT_Fn)
            
                %% FIT Data
                
            %             if (blank_fit)
            %                 freq_Scale = 1./(2*pi.*freq);
            %             else
            %                 freq_Scale =  (2*pi.*freq);; %freq./max(freq);
            %             end
                        freq_Scale = 1;
                        weighting = 1./( (real(y_z)).^2 + (imag(y_z)).^2 );
                        Real_Scale = weighting; % 1./real(y_z);
                        Imag_Scale = weighting; % 1./imag(y_z);
                        CCT_Fn_Lik = @(b) (sum( Real_Scale.*freq_Scale.*(real(y_z) - real(CCT_Fn(b,2*pi.*freq))).^2 ) ) + (sum( Imag_Scale.*freq_Scale.*(imag(y_z) - imag(CCT_Fn(b,2*pi.*freq))).^2 ) );% - 100.*log10(Upper-b(1))+ 1e3*norm(b-MaxLik,2)^2 %+ 0.001*(b(1)-500)^2
            
                            A = [];
                            b = [];
                            Aeq = [];
                            beq = [];
            
            
                        rng default % For reproducibility
                        problem = createOptimProblem('fmincon','objective',...
                            CCT_Fn_Lik,'x0',beta0,'lb',lb,'ub',ub);
            
                        ms = MultiStart;
                        ms.UseParallel = true;
                        ms.Display = 'iter';
                        ms.PlotFcn = @gsplotbestf;
                       % ms.StartPointsToRun = 'bounds';
            
                        [bet,fval] = run(ms,problem,multi_starts)
            
            %% OUTPUT
            
                        GoF_MSE = goodnessOfFit(CCT_Fn(bet,2*pi*freq),y_z,'MSE');
                        GoF_NRMSE = goodnessOfFit(CCT_Fn(bet,2*pi*freq),y_z,'NRMSE');
                        GoF_NMSE = goodnessOfFit(CCT_Fn(bet,2*pi*freq),y_z,'NMSE');
            
                        GoF = [GoF_MSE, GoF_NRMSE, GoF_NMSE];
            
                        out{1} = bet;
                        out{2} = fval;
                        out{3} = GoF;
                        out{4} = CCT_Fn(bet,2*pi.*freq);
            
                        if (sequential_Fit)
            
                            out{5} = Blank_Fits;
            
                        end
            
            
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindFileButton
        function FindFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               %disp('User selected Cancel');
            else
               %disp(['User selected ', fullfile(path,file)]);
            end

            app.FilePathEditField.Value =  fullfile(path,file) ;
            app.CurrentFileName = fullfile(path,file);
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)


            %% Set up the Import Options and import the data
            opts = delimitedTextImportOptions("NumVariables", 7);
            
            % Specify range and delimiter
            opts.DataLines = [2, Inf];
            opts.Delimiter = "\t";
            
            % Specify column names and types
            opts.VariableNames = ["Index", "FrequencyHz", "Z", "Z1", "Z2", "Phase", "Times"];
            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double"];
            
            % Specify file level properties
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            
            % Import the data
            app.CurrentEISMeasurement = readtable(app.CurrentFileName, opts);
            
            
            %% Clear temporary variables
            clear opts

            %% Save Data

            %struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'Data', {});

                app.Data(end+1) = struct('Name', {app.CurrentEISName}, 'Time', {app.CurrentEISTime}, 'ExperimentNumber', {app.CurrentExpNumber},...
                    'Well', {app.CurrentWell} ,  'Data', (app.CurrentEISMeasurement));
                %Data2Save = app.Data;
                %save("Test.mat","Data2Save")


            %% Plot
                EISDat = app.CurrentEISMeasurement;
                Freq = EISDat.FrequencyHz;
                Mod = EISDat.Z2;
                Arg = EISDat.Phase;

                switch app.HoldPlotSwitchLoad.Value
                        case 'On'
                            hold(app.LoadEISDat, 'on');

                        case 'Off'
                            hold(app.LoadEISDat, 'off');
                            cla(app.LoadEISDat)
                    end
                
                yyaxis(app.LoadEISDat, 'left')
                plot(app.LoadEISDat, Freq, Mod,'LineWidth',3)
                set(app.LoadEISDat,'YScale','log')
                set(app.LoadEISDat,'XScale','log')
                hold(app.LoadEISDat, 'on')
                     yyaxis(app.LoadEISDat, 'right')
                     plot(app.LoadEISDat, Freq, Arg,'LineWidth',3)
                     set(app.LoadEISDat,'YScale','linear')
                hold(app.LoadEISDat, 'on')

           %% Add Table Entry
                 newData = {app.CurrentEISName app.CurrentExpNumber app.CurrentWell app.CurrentEISTime};
                 app.UITable.Data = [app.UITable.Data; newData];
        end

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
            value = app.FilePathEditField.Value;
            app.CurrentFileName = value;
        end

        % Value changed function: TimePointAUEditField
        function TimePointAUEditFieldValueChanged(app, event)
            value = app.TimePointAUEditField.Value;
            app.CurrentEISTime = value;
        end

        % Value changed function: ConditionEditField
        function ConditionEditFieldValueChanged(app, event)
            value = app.ConditionEditField.Value;
            app.CurrentEISName = value;
        end

        % Value changed function: ExperimentNumberEditField
        function ExperimentNumberEditFieldValueChanged(app, event)
            value = app.ExperimentNumberEditField.Value;
            app.CurrentExpNumber = value;
        end

        % Value changed function: WellNumberEditField
        function WellNumberEditFieldValueChanged(app, event)
            value = app.WellNumberEditField.Value;
            app.CurrentWell = value;
        end

        % Button pushed function: SaveForLaterButton
        function SaveForLaterButtonPushed(app, event)
            selpath = uigetdir();
            SavedData = app.Data;
            save(selpath + "\AnalyZeData.mat","SavedData",'-mat');

            f = msgbox("Data Saved as AnalyZeData.mat :)");
        end

        % Button pushed function: LoadFromPreviousSaveButton
        function LoadFromPreviousSaveButtonPushed(app, event)
             [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);

               var = load(fullfile(path,file));
               app.Data = var.SavedData;
            end
            
            app.UITable.Data = [];
            for (i = 1:length(app.Data))
                var = app.Data;
                var = var(i);
                newData = {var.Name var.ExperimentNumber var.Well var.Time};
                app.UITable.Data = [app.UITable.Data; newData];
            end
        

            cla(app.LoadEISDat)
            hold(app.LoadEISDat, 'on');
                            
             for (i = 1:length(app.Data))
                EISDat = app.Data(i);
                EISDat = EISDat.Data;
                Freq = EISDat.FrequencyHz;
                Mod = EISDat.Z2;
                Arg = EISDat.Phase;
                
                yyaxis(app.LoadEISDat, 'left')
                plot(app.LoadEISDat, Freq, Mod,'LineWidth',3)
                set(app.LoadEISDat,'YScale','log')
                set(app.LoadEISDat,'XScale','log')
                
                     yyaxis(app.LoadEISDat, 'right')
                     plot(app.LoadEISDat, Freq, Arg,'LineWidth',3)
                     set(app.LoadEISDat,'YScale','linear')
                
             end
              hold(app.LoadEISDat, 'off');

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;


        end

        % Button pushed function: RefreshDataOptionsButton
        function RefreshDataOptionsButtonPushed(app, event)
                Dat = app.Data;

                Conditions = "Select All";
                Exp = "Select All";
                Well = "Select All";
                Time = "Select All";
                for (i=1:length(Dat))
                    Conditions(i+1) = Dat(i).Name;
                    Exp(i+1) = Dat(i).ExperimentNumber;
                    Well(i+1) = Dat(i).Well;
                    Time(i+1) = Dat(i).Time;
                end
                
                Conditions = unique(Conditions);
                Exp = unique(Exp);
                Well = unique(Well);
                Time = unique(Time);

                app.ConditionListBox.Items = Conditions;
                app.ExperimentNumberListBox.Items = Exp;
                app.WellNumberListBox.Items = Well;
                app.TimeListBox.Items = Time;
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            Condition = app.ConditionListBox.Value;
            Well = app.WellNumberListBox.Value;
            Exp = app.ExperimentNumberListBox.Value;
            Time = app.TimeListBox.Value;

            Dat = app.Data;

                ConditionsAll = "";
                ExpAll = "";
                WellAll = "";
                TimeAll = "";
                for (i=1:length(Dat))
                    ConditionsAll(i) = Dat(i).Name;
                    ExpAll(i) = Dat(i).ExperimentNumber;
                    WellAll(i) = Dat(i).Well;
                    TimeAll(i) = Dat(i).Time;
                end
                
               Indexes = [];
               switch Condition
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(ConditionsAll == Condition);
               end
                 Dat = Dat(Indexes);
                 ExpAll = ExpAll(Indexes);
                 WellAll = WellAll(Indexes);
                 TimeAll = TimeAll(Indexes);


             Indexes = [];
               switch Exp
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(ExpAll == Exp);
               end
                 Dat = Dat(Indexes);
                 WellAll = WellAll(Indexes);
                 TimeAll = TimeAll(Indexes);

             Indexes = [];
               switch Well
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(WellAll == Well);
               end
                 Dat = Dat(Indexes);
                 TimeAll = TimeAll(Indexes);

             Indexes = [];
               switch Time
                    case 'Select All'
                        Indexes = [1:length(Dat)];
                   otherwise
                        Indexes = find(TimeAll == Time);
               end
                Dat = Dat(Indexes);

             app.ChosenDataTable.Data = [];
            for (i = 1:length(Dat))
                var = Dat;
                var = var(i);
                newData = {var.Name var.ExperimentNumber var.Well var.Time};
                app.ChosenDataTable.Data = [app.ChosenDataTable.Data; newData];
            end

           app.DatToFit = Dat;

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
    
           %% Assemble Parameters

            Dat_full = app.DatToFit;

            NumDays = length(Dat_full);
            NumDevices =1;

            multi_starts = app.MultiStartsEditField.Value;
            multi_starts_blank = app.BlankFitMultiStartsEditField.Value;
           
            %fit_sequentially = true;

            switch app.FitSequentiallySwitch.Value
                case 'On'
                    fit_sequentially = true;
                case 'Off'
                    fit_sequentially = false;
            end

           switch app.FitBlankOnlySwitch.Value
                case 'On'
                    fit_blank_only = true;
                    fit_sequentially = false;
                    multi_starts = multi_starts_blank;
                case 'Off'
                    fit_blank_only = false;
            end

            fit_cct = [];
            switch app.CircuitListBox.Value
                case 'R--(R//C)--((R--W)//C)' 
                    fit_cct = "2E";
                case 'R--(R//C)--C'
                    fit_cct = "3E";
                case 'R--(R//C)--C--Q'
                    fit_cct = "3E_SS";
                case 'R--(R//C)--Q'
                    fit_cct = "2E_SS";
                otherwise
                    warning('Unexpected circuit type. No plot created.')
            end
        
            %% Fit CCT
       

            app.RunningLamp.Color = 'green';
            drawnow()

            Fits_local = [];
            for (i = 1:NumDays)

                Dat_i = Dat_full(i);
                Dat_i_EIS = Dat_i.Data;
                y_z_i = Dat_i_EIS.Z - 1j*Dat_i_EIS.Z1;
                freq_i = Dat_i_EIS.FrequencyHz;


                 Fits_local{i} = app.MultistartFit( y_z_i,... %y_z
                                            freq_i,... %freq
                                            multi_starts,... %multi_starts
                                            fit_cct,... %cct type
                                            fit_sequentially,... %fit blank first
                                            fit_blank_only, ... %fit blank now
                                            y_z_i,... %y_z blank
                                            multi_starts_blank... %multi starts
                                            ); 

                 %app.RunningLamp.Color = [0.93,0.69,0.13];

                  %% Store Results
                      app.Fits(end+1) = struct('Name', {Dat_i.Name},...
                          'Time', {Dat_i.Time},...
                          'ExperimentNumber', {Dat_i.ExperimentNumber},...
                          'Well', {Dat_i.Well} ,...
                          'FitsResults', (Fits_local(i))...
                          );

                  %% Add Table Entry
                        Results = Fits_local{i};
                        GoF_results = Results{3};
                        Beta_result = Results{1};


                        if (fit_blank_only)
                            Rb_Result = NaN;
                            Cb_Result = NaN;

                           Beta_device_results = Beta_result;

                        else
                            Rb_Result = Beta_result(1);
                            Cb_Result = Beta_result(2);

                            if (fit_sequentially)
                                Sequential_Blank_Result = Results{5};
                                Beta_device_results = cell2mat(Sequential_Blank_Result(1));
                            else
                                Beta_device_results = (Beta_result(3:end));
                            end
                        end

                        Beta_device_results = mat2str(Beta_device_results,4);
                        
                     newData = {app.CircuitListBox.Value Dat_i.Name Dat_i.ExperimentNumber Dat_i.Well Dat_i.Time Rb_Result Cb_Result Beta_device_results GoF_results(1) GoF_results(2) GoF_results(3)};
                     DataToAddToTable = [app.ResultsTable.Data; newData];
                     app.ResultsTable.Data = DataToAddToTable;


                  %% plot Results
                    
                    Results = Fits_local{i};
                    display(Results)
                    Results_yz = Results{4};
                    Mod_Results = abs(Results_yz);
                    Arg_Results = -1.*angle(Results_yz)*(180/pi);
                    
                    Freq = Dat_i_EIS.FrequencyHz;
                    Mod = Dat_i_EIS.Z2;
                    Arg = Dat_i_EIS.Phase;

                    switch app.HoldPlotsSwitch.Value
                        case 'On'
                            hold(app.BodeResults, 'on');

                            hold(app.NyqResults,'on' );
                        case 'Off'
                            hold(app.BodeResults, 'off');
                            cla(app.BodeResults)

                            hold(app.NyqResults,'off' );
                            cla(app.NyqResults)
                    end
                                    
                    yyaxis(app.BodeResults, 'left')
                    plot(app.BodeResults, Freq, Mod, '*r','LineWidth',1)
                    set(app.BodeResults,'YScale','log')
                    set(app.BodeResults,'XScale','log')
                    xlabel(app.BodeResults,'Frequency (Hz)');
                    ylabel(app.BodeResults,'Magnitude, |Z|, (\Omega )');
                    hold(app.BodeResults, 'on')
                        plot(app.BodeResults, Freq, Mod_Results, '-r','LineWidth',1)

                         yyaxis(app.BodeResults, 'right')
                           ylabel(app.BodeResults,'-Phase, \angle Z (deg)');
                         plot(app.BodeResults, Freq, Arg, '*g','LineWidth',1)
                         plot(app.BodeResults, Freq, Arg_Results, '-g','LineWidth',1)
                         set(app.BodeResults,'YScale','linear')
                    hold(app.BodeResults, 'off')
                    
       
                    plot(app.NyqResults , Dat_i_EIS.Z, Dat_i_EIS.Z1, '*r','LineWidth',1)
                    hold(app.NyqResults, 'on')
                        plot(app.NyqResults , real(Results_yz), -1.*imag(Results_yz), '-k','LineWidth',1)
                    hold(app.NyqResults, 'off')
                    xlabel(app.NyqResults,'Real(Z)');
                    ylabel( app.NyqResults, '-Imag(Z)');

                    drawnow()
                    
            end
            
            app.RunningLamp.Color = 'red';
            drawnow()


        end

        % Button pushed function: ClearResultsButton
        function ClearResultsButtonPushed(app, event)
             yyaxis(app.BodeResults, 'right')
             cla(app.BodeResults)
             yyaxis(app.BodeResults, 'left')
             cla(app.BodeResults)
             cla(app.NyqResults)
             app.ResultsTable.Data = [];

             Fits = struct('Name', {'Start'}, 'Time', {-1}, 'ExperimentNumber', {-1}, 'Well', {'A0'} , 'FitsResults', {});
             
             f = msgbox("Results Cleared!");
       
             
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            selpath = uigetdir();
            SavedDataResults = app.Fits;
            save(selpath + "\AnalyZeResults.mat","SavedDataResults",'-mat');

            T_c =  app.ResultsTable.Data;
            T = cell2table(T_c, 'VariableNames', {'circuit','Condition','exp','cell','Time','Rb','Cb','Device CCT Params','mse','rmse','nmse'});

            filename= selpath + "\AnalyZeResults.csv";
            writetable(T,filename);

            f = msgbox("Data Saved as AnalyZeResults.mat and AnalyZeResults.csv :D");
        end

        % Value changed function: FitBlankOnlySwitch
        function FitBlankOnlySwitchValueChanged(app, event)
            value = app.FitBlankOnlySwitch.Value;

            switch value
                case 'On'
                    app.FitSequentiallySwitch.Value = 'Off';
                case 'Off'
                   
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Bioelectronicist.png');
            app.AnalyZeLabel = uilabel(app.HomeTab);
            app.AnalyZeLabel.Text = 'AnalyZe';
            app.ImpedanceDataAnalysisToolLabel = uilabel(app.HomeTab);
            app.ImpedanceDataAnalysisToolLabel.Text = 'Impedance Data Analysis Tool';
            app.BetaLabel = uilabel(app.HomeTab);
            app.BetaLabel.Text = 'Beta!';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BEST_Logo.png');
            app.DouglasvNVersion1Feb2023Label = uilabel(app.HomeTab);
            app.DouglasvNVersion1Feb2023Label.Text = 'Douglas v. N. Version 1 Feb 2023';
            app.InportDataTab = uitab(app.TabGroup);
            app.InportDataTab.Title = 'Inport Data';
            app.LoadDataPanel = uipanel(app.InportDataTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadEISDat = uiaxes(app.LoadDataPanel);
            title(app.LoadEISDat, 'Bode Plot')
            xlabel(app.LoadEISDat, 'Frequency (Hz)')
            app.LoadEISDat.Tag = 'LoadEIS';
            app.TimePointAUEditFieldLabel = uilabel(app.LoadDataPanel);
            app.TimePointAUEditFieldLabel.Text = 'Time Point (AU)';
            app.ConditionEditFieldLabel = uilabel(app.LoadDataPanel);
            app.ConditionEditFieldLabel.Text = 'Condition';
            app.ConditionEditField = uieditfield(app.LoadDataPanel, 'text');
            app.ConditionEditField.ValueChangedFcn = createCallbackFcn(app, @ConditionEditFieldValueChanged, true);
            app.TimePointAUEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.TimePointAUEditField.ValueChangedFcn = createCallbackFcn(app, @TimePointAUEditFieldValueChanged, true);
            app.TimePointAUEditField.Value = -1;
            app.FilePathEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.LoadDataPanel, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.FindFileButton = uibutton(app.LoadDataPanel, 'push');
            app.FindFileButton.ButtonPushedFcn = createCallbackFcn(app, @FindFileButtonPushed, true);
            app.FindFileButton.Text = 'Find File';
            app.LOADButton = uibutton(app.LoadDataPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.ExperimentNumberEditFieldLabel = uilabel(app.LoadDataPanel);
            app.ExperimentNumberEditFieldLabel.Text = 'Experiment Number';
            app.ExperimentNumberEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.ExperimentNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentNumberEditFieldValueChanged, true);
            app.ExperimentNumberEditField.Value = -1;
            app.WellNumberEditFieldLabel = uilabel(app.LoadDataPanel);
            app.WellNumberEditFieldLabel.Text = 'Well Number';
            app.WellNumberEditField = uieditfield(app.LoadDataPanel, 'text');
            app.WellNumberEditField.ValueChangedFcn = createCallbackFcn(app, @WellNumberEditFieldValueChanged, true);
            app.HoldPlotSwitchLabel = uilabel(app.LoadDataPanel);
            app.HoldPlotSwitchLabel.Text = 'Hold Plot';
            app.HoldPlotSwitchLoad = uiswitch(app.LoadDataPanel, 'toggle');
            app.UITable = uitable(app.InportDataTab);
            app.UITable.ColumnName = {'Condition'; 'Experiment Number'; 'Well'; 'Time Point'};
            app.UITable.RowName = {};
            app.LoadFromPreviousSaveButton = uibutton(app.InportDataTab, 'push');
            app.LoadFromPreviousSaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromPreviousSaveButtonPushed, true);
            app.LoadFromPreviousSaveButton.Text = 'Load From Previous Save';
            app.SaveForLaterButton = uibutton(app.InportDataTab, 'push');
            app.SaveForLaterButton.ButtonPushedFcn = createCallbackFcn(app, @SaveForLaterButtonPushed, true);
            app.SaveForLaterButton.Text = 'Save For Later';
            app.AnalysisCCTFITTab = uitab(app.TabGroup);
            app.AnalysisCCTFITTab.Title = 'Analysis CCT FIT';
            app.TrimData = uipanel(app.AnalysisCCTFITTab);
            app.TrimData.Title = 'Select Time Series';
            app.ConditionListBoxLabel = uilabel(app.TrimData);
            app.ConditionListBoxLabel.Text = 'Condition';
            app.ConditionListBox = uilistbox(app.TrimData);
            app.ExperimentNumberListBoxLabel = uilabel(app.TrimData);
            app.ExperimentNumberListBoxLabel.Text = 'Experiment Number';
            app.ExperimentNumberListBox = uilistbox(app.TrimData);
            app.WellNumberListBoxLabel = uilabel(app.TrimData);
            app.WellNumberListBoxLabel.Text = 'Well Number';
            app.WellNumberListBox = uilistbox(app.TrimData);
            app.ChosenDataTable = uitable(app.TrimData);
            app.ChosenDataTable.ColumnName = {'Condition'; 'Experiment Number'; 'Well'; 'Time Point'};
            app.ChosenDataTable.RowName = {};
            app.ChooseButton = uibutton(app.TrimData, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.TimeListBoxLabel = uilabel(app.TrimData);
            app.TimeListBoxLabel.Text = 'Time';
            app.TimeListBox = uilistbox(app.TrimData);
            app.FittingParams = uipanel(app.AnalysisCCTFITTab);
            app.FittingParams.Title = 'Choose Fitting Parameters';
            app.MultiStartsEditFieldLabel = uilabel(app.FittingParams);
            app.MultiStartsEditFieldLabel.Text = 'MultiStarts';
            app.MultiStartsEditField = uieditfield(app.FittingParams, 'numeric');
            app.MultiStartsEditField.Value = 3000;
            app.BlankFitMultiStartsEditFieldLabel = uilabel(app.FittingParams);
            app.BlankFitMultiStartsEditFieldLabel.Text = {'Blank Fit'; 'MultiStarts'};
            app.BlankFitMultiStartsEditField = uieditfield(app.FittingParams, 'numeric');
            app.BlankFitMultiStartsEditField.Value = 1000;
            app.FitSequentiallySwitchLabel = uilabel(app.FittingParams);
            app.FitSequentiallySwitchLabel.Text = 'Fit Sequentially';
            app.FitSequentiallySwitch = uiswitch(app.FittingParams, 'slider');
            app.FitBlankOnlySwitchLabel = uilabel(app.FittingParams);
            app.FitBlankOnlySwitchLabel.Text = 'Fit Blank Only';
            app.FitBlankOnlySwitch = uiswitch(app.FittingParams, 'slider');
            app.FitBlankOnlySwitch.ValueChangedFcn = createCallbackFcn(app, @FitBlankOnlySwitchValueChanged, true);
            app.CircuitListBoxLabel = uilabel(app.FittingParams);
            app.CircuitListBoxLabel.Text = 'Circuit';
            app.CircuitListBox = uilistbox(app.FittingParams);
            app.CircuitListBox.Items = {'R--(R//C)--((R--W)//C)', 'R--(R//C)--C', 'R--(R//C)--C--Q', 'R--(R//C)--Q'};
            app.CircuitListBox.Value = 'R--(R//C)--((R--W)//C)';
            app.GoButton = uibutton(app.FittingParams, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.RunningLampLabel = uilabel(app.FittingParams);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.FittingParams);
            app.RefreshDataOptionsButton = uibutton(app.AnalysisCCTFITTab, 'push');
            app.RefreshDataOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDataOptionsButtonPushed, true);
            app.RefreshDataOptionsButton.Text = 'Refresh Data Options';
            app.TabGroup2 = uitabgroup(app.AnalysisCCTFITTab);
            app.PlotsTab = uitab(app.TabGroup2);
            app.PlotsTab.Title = 'Plots';
            app.BodeResults = uiaxes(app.PlotsTab);
            xlabel(app.BodeResults, 'X')
            ylabel(app.BodeResults, 'Y')
            zlabel(app.BodeResults, 'Z')
            app.NyqResults = uiaxes(app.PlotsTab);
            xlabel(app.NyqResults, 'X')
            ylabel(app.NyqResults, 'Y')
            zlabel(app.NyqResults, 'Z')
            app.HoldPlotsSwitchLabel = uilabel(app.PlotsTab);
            app.HoldPlotsSwitchLabel.Text = 'Hold Plots';
            app.HoldPlotsSwitch = uiswitch(app.PlotsTab, 'toggle');
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.ResultsTable = uitable(app.ResultsTab);
            app.ResultsTable.ColumnName = {'Circuit'; 'Condition'; 'Exp'; 'Well'; 'Time'; 'Rb'; 'Cb'; 'Device CCT Params'; 'MSE'; 'NRMSE'; 'NMSE'};
            app.ResultsTable.RowName = {};
            app.SaveResultsButton = uibutton(app.AnalysisCCTFITTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.ClearResultsButton = uibutton(app.AnalysisCCTFITTab, 'push');
            app.ClearResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResultsButtonPushed, true);
            app.ClearResultsButton.Text = 'Clear Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyZe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end